classdef LLT1mapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T1mappUIFigure               matlab.ui.Figure
        T1Label                      matlab.ui.control.Label
        TabGroup4                    matlab.ui.container.TabGroup
        SequenceparametersTab        matlab.ui.container.Tab
        DynamicsViewField            matlab.ui.control.NumericEditField
        EchoesLabel_2                matlab.ui.control.Label
        TISEditField                 matlab.ui.control.NumericEditField
        ESPmsLabel                   matlab.ui.control.Label
        DatafileEditField            matlab.ui.control.EditField
        DatafileEditFieldLabel       matlab.ui.control.Label
        AspectRatioViewField         matlab.ui.control.NumericEditField
        AspectratioLabel             matlab.ui.control.Label
        SLTViewField                 matlab.ui.control.NumericEditField
        SlicethicknessmmLabel        matlab.ui.control.Label
        FOVViewField                 matlab.ui.control.NumericEditField
        FOVmmEditFieldLabel          matlab.ui.control.Label
        EchoesViewField              matlab.ui.control.NumericEditField
        EchoesLabel                  matlab.ui.control.Label
        SlicesViewField              matlab.ui.control.NumericEditField
        SlicesEditFieldLabel         matlab.ui.control.Label
        dimyViewField                matlab.ui.control.NumericEditField
        dimyEditFieldLabel           matlab.ui.control.Label
        dimxViewField                matlab.ui.control.NumericEditField
        dimxEditFieldLabel           matlab.ui.control.Label
        TI0EditField                 matlab.ui.control.NumericEditField
        TE0msLabel                   matlab.ui.control.Label
        TRViewField                  matlab.ui.control.NumericEditField
        TRmsEditFieldLabel           matlab.ui.control.Label
        TabGroup2                    matlab.ui.container.TabGroup
        RawimagesTab                 matlab.ui.container.Tab
        LevelEditField               matlab.ui.control.NumericEditField
        LEditFieldLabel              matlab.ui.control.Label
        WindowEditField              matlab.ui.control.NumericEditField
        WEditFieldLabel              matlab.ui.control.Label
        AutoLWButton                 matlab.ui.control.StateButton
        LevelSlider                  matlab.ui.control.Slider
        WindowSlider                 matlab.ui.control.Slider
        RawImage                     matlab.ui.control.Image
        MasksettingsPanel            matlab.ui.container.Panel
        ThresholdEditField           matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel      matlab.ui.control.Label
        OpenButton                   matlab.ui.control.Button
        CloseButton                  matlab.ui.control.Button
        DilateButton                 matlab.ui.control.Button
        ErodeButton                  matlab.ui.control.Button
        ThresholdSlider              matlab.ui.control.Slider
        ImageselectionPanel          matlab.ui.container.Panel
        DynamicsEditField1           matlab.ui.control.NumericEditField
        DynamicLabel                 matlab.ui.control.Label
        DynamicsSlider1              matlab.ui.control.Slider
        EchoesEditField              matlab.ui.control.NumericEditField
        InversionLabel               matlab.ui.control.Label
        SlicesEditField1             matlab.ui.control.NumericEditField
        SliceEditFieldLabel          matlab.ui.control.Label
        EchoesSlider                 matlab.ui.control.Slider
        SlicesSlider1                matlab.ui.control.Slider
        ImageLabel                   matlab.ui.control.Label
        RawDataFig                   matlab.ui.control.UIAxes
        RecoparametersTab            matlab.ui.container.Tab
        CSregularizationLabel        matlab.ui.control.Label
        LLRxyzEditFieldLabel         matlab.ui.control.Label
        TVdynEditFieldLabel          matlab.ui.control.Label
        WVxyzEditField               matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel          matlab.ui.control.Label
        TVxyzEditField               matlab.ui.control.NumericEditField
        TVxyzEditFieldLabel          matlab.ui.control.Label
        LLRxyzEditField              matlab.ui.control.NumericEditField
        TVteEditField                matlab.ui.control.NumericEditField
        TVdynEditField               matlab.ui.control.NumericEditField
        TVteEditFieldLabel           matlab.ui.control.Label
        FilteringLabel               matlab.ui.control.Label
        DeNoiseWindowEditField       matlab.ui.control.NumericEditField
        DeNoiseLabel                 matlab.ui.control.Label
        DeNoiseCheckBox              matlab.ui.control.CheckBox
        DeRingLabel                  matlab.ui.control.Label
        DeRingCheckBox               matlab.ui.control.CheckBox
        FitparametersTab             matlab.ui.container.Tab
        DynamicsSelectionPanel       matlab.ui.container.Panel
        LastDynamicEditField         matlab.ui.control.NumericEditField
        LastsliceLabel_2             matlab.ui.control.Label
        FirstDynamicEditField        matlab.ui.control.NumericEditField
        FirstsliceLabel_2            matlab.ui.control.Label
        InversiontimeselectionPanel  matlab.ui.container.Panel
        CheckBox_96                  matlab.ui.control.CheckBox
        CheckBox_95                  matlab.ui.control.CheckBox
        CheckBox_94                  matlab.ui.control.CheckBox
        CheckBox_93                  matlab.ui.control.CheckBox
        CheckBox_92                  matlab.ui.control.CheckBox
        CheckBox_91                  matlab.ui.control.CheckBox
        CheckBox_90                  matlab.ui.control.CheckBox
        CheckBox_89                  matlab.ui.control.CheckBox
        CheckBox_88                  matlab.ui.control.CheckBox
        CheckBox_87                  matlab.ui.control.CheckBox
        CheckBox_86                  matlab.ui.control.CheckBox
        CheckBox_85                  matlab.ui.control.CheckBox
        CheckBox_84                  matlab.ui.control.CheckBox
        CheckBox_83                  matlab.ui.control.CheckBox
        CheckBox_82                  matlab.ui.control.CheckBox
        CheckBox_81                  matlab.ui.control.CheckBox
        CheckBox_80                  matlab.ui.control.CheckBox
        CheckBox_79                  matlab.ui.control.CheckBox
        CheckBox_78                  matlab.ui.control.CheckBox
        CheckBox_77                  matlab.ui.control.CheckBox
        CheckBox_76                  matlab.ui.control.CheckBox
        CheckBox_75                  matlab.ui.control.CheckBox
        CheckBox_74                  matlab.ui.control.CheckBox
        CheckBox_73                  matlab.ui.control.CheckBox
        CheckBox_72                  matlab.ui.control.CheckBox
        CheckBox_71                  matlab.ui.control.CheckBox
        CheckBox_70                  matlab.ui.control.CheckBox
        CheckBox_69                  matlab.ui.control.CheckBox
        CheckBox_68                  matlab.ui.control.CheckBox
        CheckBox_67                  matlab.ui.control.CheckBox
        CheckBox_66                  matlab.ui.control.CheckBox
        CheckBox_65                  matlab.ui.control.CheckBox
        CheckBox_64                  matlab.ui.control.CheckBox
        CheckBox_63                  matlab.ui.control.CheckBox
        CheckBox_62                  matlab.ui.control.CheckBox
        CheckBox_61                  matlab.ui.control.CheckBox
        CheckBox_60                  matlab.ui.control.CheckBox
        CheckBox_59                  matlab.ui.control.CheckBox
        CheckBox_58                  matlab.ui.control.CheckBox
        CheckBox_57                  matlab.ui.control.CheckBox
        CheckBox_56                  matlab.ui.control.CheckBox
        CheckBox_55                  matlab.ui.control.CheckBox
        CheckBox_54                  matlab.ui.control.CheckBox
        CheckBox_53                  matlab.ui.control.CheckBox
        CheckBox_52                  matlab.ui.control.CheckBox
        CheckBox_51                  matlab.ui.control.CheckBox
        CheckBox_50                  matlab.ui.control.CheckBox
        CheckBox_49                  matlab.ui.control.CheckBox
        CheckBox_48                  matlab.ui.control.CheckBox
        CheckBox_47                  matlab.ui.control.CheckBox
        CheckBox_46                  matlab.ui.control.CheckBox
        CheckBox_45                  matlab.ui.control.CheckBox
        CheckBox_44                  matlab.ui.control.CheckBox
        CheckBox_43                  matlab.ui.control.CheckBox
        CheckBox_42                  matlab.ui.control.CheckBox
        CheckBox_41                  matlab.ui.control.CheckBox
        CheckBox_40                  matlab.ui.control.CheckBox
        CheckBox_39                  matlab.ui.control.CheckBox
        CheckBox_38                  matlab.ui.control.CheckBox
        CheckBox_37                  matlab.ui.control.CheckBox
        CheckBox_36                  matlab.ui.control.CheckBox
        CheckBox_35                  matlab.ui.control.CheckBox
        CheckBox_34                  matlab.ui.control.CheckBox
        CheckBox_33                  matlab.ui.control.CheckBox
        CheckBox_32                  matlab.ui.control.CheckBox
        CheckBox_31                  matlab.ui.control.CheckBox
        CheckBox_30                  matlab.ui.control.CheckBox
        CheckBox_29                  matlab.ui.control.CheckBox
        CheckBox_28                  matlab.ui.control.CheckBox
        CheckBox_27                  matlab.ui.control.CheckBox
        CheckBox_26                  matlab.ui.control.CheckBox
        CheckBox_25                  matlab.ui.control.CheckBox
        CheckBox_24                  matlab.ui.control.CheckBox
        CheckBox_23                  matlab.ui.control.CheckBox
        CheckBox_22                  matlab.ui.control.CheckBox
        CheckBox_21                  matlab.ui.control.CheckBox
        CheckBox_20                  matlab.ui.control.CheckBox
        CheckBox_19                  matlab.ui.control.CheckBox
        CheckBox_18                  matlab.ui.control.CheckBox
        CheckBox_17                  matlab.ui.control.CheckBox
        CheckBox_16                  matlab.ui.control.CheckBox
        CheckBox_15                  matlab.ui.control.CheckBox
        CheckBox_14                  matlab.ui.control.CheckBox
        CheckBox_13                  matlab.ui.control.CheckBox
        CheckBox_12                  matlab.ui.control.CheckBox
        CheckBox_11                  matlab.ui.control.CheckBox
        CheckBox_10                  matlab.ui.control.CheckBox
        CheckBox_9                   matlab.ui.control.CheckBox
        CheckBox_8                   matlab.ui.control.CheckBox
        CheckBox_7                   matlab.ui.control.CheckBox
        CheckBox_6                   matlab.ui.control.CheckBox
        CheckBox_5                   matlab.ui.control.CheckBox
        CheckBox_4                   matlab.ui.control.CheckBox
        CheckBox_3                   matlab.ui.control.CheckBox
        CheckBox_2                   matlab.ui.control.CheckBox
        CheckBox_1                   matlab.ui.control.CheckBox
        GoodnessoffitPanel           matlab.ui.container.Panel
        Rsquare                      matlab.ui.control.NumericEditField
        MinRsquareLabel              matlab.ui.control.Label
        SliceselectionPanel          matlab.ui.container.Panel
        LastSliceEditField           matlab.ui.control.NumericEditField
        LastsliceLabel               matlab.ui.control.Label
        FirstSliceEditField          matlab.ui.control.NumericEditField
        FirstsliceLabel              matlab.ui.control.Label
        TabGroup3                    matlab.ui.container.TabGroup
        T1mapTab                     matlab.ui.container.Tab
        BrushEditField               matlab.ui.control.NumericEditField
        brushEditFieldLabel          matlab.ui.control.Label
        T1msLabel2                   matlab.ui.control.Label
        ExportRoiDataButton          matlab.ui.control.Button
        ROIaveragedLabel             matlab.ui.control.Label
        pixelValueLabel              matlab.ui.control.Label
        T1ViewField2                 matlab.ui.control.EditField
        T1msLabel                    matlab.ui.control.Label
        T1ViewField                  matlab.ui.control.EditField
        EraseButton                  matlab.ui.control.Button
        ROIbutton                    matlab.ui.control.Button
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        T1Image                      matlab.ui.control.Image
        ColormapButtonGroup          matlab.ui.container.ButtonGroup
        T1ScaleAutoButton            matlab.ui.control.Button
        T1ScaleMaxLabel              matlab.ui.control.Label
        T1ScaleEditField             matlab.ui.control.NumericEditField
        hotButton                    matlab.ui.control.RadioButton
        parulaButton                 matlab.ui.control.RadioButton
        winterButton                 matlab.ui.control.RadioButton
        autumnButton                 matlab.ui.control.RadioButton
        grayButton                   matlab.ui.control.RadioButton
        ImageselectionPanel_2        matlab.ui.container.Panel
        DynamicsSlider2              matlab.ui.control.Slider
        DynamicsEditField2           matlab.ui.control.NumericEditField
        DynamicLabel_2               matlab.ui.control.Label
        SlicesEditField2             matlab.ui.control.NumericEditField
        SliceEditField_2Label        matlab.ui.control.Label
        SlicesSlider2                matlab.ui.control.Slider
        T1MapLabel                   matlab.ui.control.Label
        T1linearPlot                 matlab.ui.control.UIAxes
        T1MapFig                     matlab.ui.control.UIAxes
        M0R2Tab                      matlab.ui.container.Tab
        BrushEditField2              matlab.ui.control.NumericEditField
        brushEditField_2Label        matlab.ui.control.Label
        EraseButton2                 matlab.ui.control.Button
        ROIbutton2                   matlab.ui.control.Button
        R2Image                      matlab.ui.control.Image
        M0Image                      matlab.ui.control.Image
        ColorMapButtonGroup3         matlab.ui.container.ButtonGroup
        hotButton3                   matlab.ui.control.RadioButton
        parulaButton3                matlab.ui.control.RadioButton
        winterButton3                matlab.ui.control.RadioButton
        autumnButton3                matlab.ui.control.RadioButton
        grayButton3                  matlab.ui.control.RadioButton
        ColorMapButtonGroup2         matlab.ui.container.ButtonGroup
        hotButton2                   matlab.ui.control.RadioButton
        parulaButton2                matlab.ui.control.RadioButton
        winterButton2                matlab.ui.control.RadioButton
        autumnButton2                matlab.ui.control.RadioButton
        grayButton2                  matlab.ui.control.RadioButton
        ImageSelectorPanel2          matlab.ui.container.Panel
        DynamicsEditField3           matlab.ui.control.NumericEditField
        DynamicLabel_3               matlab.ui.control.Label
        DynamicsSlider3              matlab.ui.control.Slider
        SlicesEditField3             matlab.ui.control.NumericEditField
        SliceEditField_2Label_2      matlab.ui.control.Label
        SlicesSlider3                matlab.ui.control.Slider
        R2MapLabel                   matlab.ui.control.Label
        M0MapLabel                   matlab.ui.control.Label
        R2MapFig                     matlab.ui.control.UIAxes
        M0MapFig                     matlab.ui.control.UIAxes
        TabGroup1                    matlab.ui.container.TabGroup
        DashboardTab                 matlab.ui.container.Tab
        AbortRegButton               matlab.ui.control.Button
        EstimatedFitTimeViewField    matlab.ui.control.EditField
        EstimatedRegTimeViewField    matlab.ui.control.EditField
        RegistrationDropDown         matlab.ui.control.DropDown
        CSrecoLabel                  matlab.ui.control.Label
        CSrecoCheckBox               matlab.ui.control.CheckBox
        ModelBasedLabel              matlab.ui.control.Label
        ModelBasedCheckBox           matlab.ui.control.CheckBox
        RegProgressLabel             matlab.ui.control.Label
        RegProgressGauge             matlab.ui.control.LinearGauge
        Lamp3                        matlab.ui.control.Lamp
        RegisterButton               matlab.ui.control.Button
        AbortFitButton               matlab.ui.control.Button
        ExportGIFButton              matlab.ui.control.Button
        Lamp7                        matlab.ui.control.Lamp
        FitProgressLabel             matlab.ui.control.Label
        ExportDCMButton              matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
        Lamp6                        matlab.ui.control.Lamp
        FitProgressGauge             matlab.ui.control.LinearGauge
        Lamp5                        matlab.ui.control.Lamp
        FitT1Button                  matlab.ui.control.Button
        Lamp4                        matlab.ui.control.Lamp
        MaskButton                   matlab.ui.control.Button
        Lamp2                        matlab.ui.control.Lamp
        RecoimagesButton             matlab.ui.control.Button
        Lamp1                        matlab.ui.control.Lamp
        LoadMRDfileButton            matlab.ui.control.Button
        MRSImage                     matlab.ui.control.Image
        statusLabel                  matlab.ui.control.Label
        StatusImage                  matlab.ui.control.Image
        BackgroundBox                matlab.ui.control.TextArea
        MessageBox                   matlab.ui.control.TextArea
        TulpLogo                     matlab.ui.control.Image
        CreditsLabel                 matlab.ui.control.Label
        LookLockerTMappingLabel      matlab.ui.control.Label
    end


    % --------------------------------------------------------------------------------------
    %
    %   Inversion recovery T1 mapping tool
    %   
    %   Gustav Strijkers
    %   Amsterdam UMC
    %   August 2022
    %
    %
    %
    %   Version 1.0; aug 2022
    %   - intial version
    %   - model-based reco
    %   - CS reconstruction 
    %
    %   Version 1.1; Feb 2023
    %   - some bug fixes
    %
    %   Version 1.2; Apr 2023
    %   - bug fix import segmented k-space data
    %   - deRing and deNoise options
    %   - Improved GIF, DICOM export (sept 23)
    %
    %   Version 1.3; Sept 2023
    %   - Lots of improvements and bug fixes
    %
    %   Version 1.4: Oct 2023
    %   - CS reco parameters menu
    %
    %


    properties (Access = public)

        % App version
        appVersion = "1.4.1"        % App version
        bartVersion                 % Bart version
       
        % File variables
        appDir                      % Application directory
        oldDir                      % Starting directory
        mrdImportPath               % Dicom import path
        dicomExportPath             % Dicom export path
        gifExportPath               % gif export path
        roiExportPath               % roi export path
        dataFile                    % raw data file
        data                        % raw k-space data
        tag                         % file tag for export of dicom files
        
        % Flags
        validDataFlag               % valid data file true/false
        validRecoFlag               % valid reco true/false
        validFitFlag                % valid fit true/false
        validRegFlag                % valid registration true/false
        mrdFlag                     % standard mrd import path already defined true or false
        multiCoilFlag               % multi-coil data
        bartDetectedFlag            % bart toolbox available true/false
        mobaFlag                    % model-based reco button on/off true/false
        roiFlag                     % roi drawing true or false
        abortRegFlag                % abort registration
        abortFitFlag                % abort fit

        % Scan properties
        dimensions                  % image dimensions
        parameters                  % image paramters
        ir                          % number of echoes
        dimx                        % nr points readout
        dimy                        % nt points phase encoding
        fov                         % field of view
        aspectRatio                 % field of view ratio
        sl                          % slice thickness
        ns                          % number of slices
        nd                          % number of dynamics
        tr                          % repetition time
        ti                          % echo time / spacing
        tis                         % all echo times
        esp                         % echo spacing
        orientation                 % phase orientation
        nrCoils                     % number of RF receiver coils
        dataType                    % type of data, e.g. spin-echo or grad-echo
        oversample                  % readout oversample factor
      
        % Images & plots
        images                      % reconstructed raw images (inversion, x, y, slice, dynamic)
        threshold                   % segmentation threshold (ns,nd,1)
        mask                        % binary image with segmentation (x, y, slice, dynamic)
        norm                        % image normalization factor
        scalefactor                 % image intensity scale factor
        txt1                        % legend label
        txt2                        % legend label
        crossT1                     % cross-hair
        crossM0                     % cross-hair
        roiPoints                   % roi point coordinates
        roiT1                       % roi drawing object
        roiM0                       % roi drawing object
        structuringElement2D        % 2D structuring element

        % Fitting
        t1map                       % resulting t1map (x, y, slice, dynamic)
        t1cmap                      % T1 color mapping
        m0map                       % resulting m0map (x, y, slice, dynamic)
        m0cmap                      % M0 color mapping
        r2map                       % resulting R2 map
        r2cmap                      % R2 color mapping
        tiSelection                 % TE selection array
        roiIntData                  % roi intensities as function of TI
        roiIntDataSigned            % roi intensities as function of TI signed
        
        % Colors
        brightred                   % red
        red                         % red
        brightgreen                 % green
        green                       % green
        yellow                      % yellow
        orange                      % orange
        blue                        % blue
        grey                        % grey

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
        
    end


    methods (Access = private)
        
        
        %------------------------------------------------
        %
        %  GUI layout
        %
        %------------------------------------------------


        % Traffic lights
        function SetLights(app,code)
            
            if code(1) == 0 app.Lamp1.Color = app.red; end %#ok<*SEPEX> 
            if code(2) == 0 app.Lamp2.Color = app.red; end
            if code(3) == 0 app.Lamp3.Color = app.red; end
            if code(4) == 0 app.Lamp4.Color = app.red; end
            if code(5) == 0 app.Lamp5.Color = app.red; end
            if code(6) == 0 app.Lamp6.Color = app.red; end
            if code(7) == 0 app.Lamp7.Color = app.red; end
                
            if code(1) == 1 app.Lamp1.Color = app.green; end
            if code(2) == 1 app.Lamp2.Color = app.green; end
            if code(3) == 1 app.Lamp3.Color = app.green; end
            if code(4) == 1 app.Lamp4.Color = app.green; end
            if code(5) == 1 app.Lamp5.Color = app.green; end
            if code(6) == 1 app.Lamp6.Color = app.green; end
            if code(7) == 1 app.Lamp7.Color = app.green; end
            
            drawnow;
            
        end
        
        
        % Buttons on/off
        function SetButtons(app,code)
        
            if code(1) == 0 app.LoadMRDfileButton.Enable = 'off';   else app.LoadMRDfileButton.Enable = 'on';   end
            if code(2) == 0 app.RecoimagesButton.Enable = 'off';          else app.RecoimagesButton.Enable = 'on';          end
            if code(3) == 0
                app.RegisterButton.Enable = 'off';
                app.RegistrationDropDown.Enable = 'off';
            else
                app.RegisterButton.Enable = 'on';
                app.RegistrationDropDown.Enable = 'on';
            end
            if code(4) == 0 app.MaskButton.Enable = 'off';     else app.MaskButton.Enable = 'on';     end
            if code(5) == 0 app.FitT1Button.Enable = 'off';         else app.FitT1Button.Enable = 'on';         end
            if code(6) == 0 app.ExportDCMButton.Enable = 'off';   else app.ExportDCMButton.Enable = 'on';   end
            if code(7) == 0 app.ExportGIFButton.Enable = 'off';     else app.ExportGIFButton.Enable = 'on';     end
            if code(8) == 0 app.ExitButton.Enable = 'off';          else app.ExitButton.Enable = 'on';          end
        
            drawnow;
            
        end
        
        
        % Switch fit parameters buttons on/off
        function SetFitButtons(app,code)
            
            if code == 0
                
                app.FirstSliceEditField.Enable = 'off';
                app.LastSliceEditField.Enable = 'off';
                app.FirstDynamicEditField.Enable = 'off';
                app.LastDynamicEditField.Enable = 'off';
                app.Rsquare.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
                app.CSrecoCheckBox.Enable = 'off';
                app.CSrecoLabel.Enable = 'off';

                app.DeNoiseCheckBox.Enable = 'off';
                app.DeNoiseLabel.Enable = 'off';
                app.DeNoiseWindowEditField.Enable = 'off';
                app.DeRingCheckBox.Enable = 'off';
                app.DeRingLabel.Enable = 'off';

                app.WVxyzEditField.Enable = 'off';
                app.WVxyzEditFieldLabel.Enable = 'off';
                app.TVxyzEditField.Enable = 'off';
                app.TVxyzEditFieldLabel.Enable = 'off';
                app.LLRxyzEditField.Enable = 'off';
                app.LLRxyzEditFieldLabel.Enable = 'off';
                app.TVteEditField.Enable = 'off';
                app.TVteEditFieldLabel.Enable = 'off';
                app.TVdynEditField.Enable = 'off';
                app.TVdynEditFieldLabel.Enable = 'off';

                app.ModelBasedCheckBox.Enable = 'off';
                app.ModelBasedLabel.Enable = 'off';

                app.CheckBox_1.Enable = 'off';  app.CheckBox_2.Enable = 'off';  app.CheckBox_3.Enable = 'off';  app.CheckBox_4.Enable = 'off';
                app.CheckBox_5.Enable = 'off';  app.CheckBox_6.Enable = 'off';  app.CheckBox_7.Enable = 'off';  app.CheckBox_8.Enable = 'off';
                app.CheckBox_9.Enable = 'off';  app.CheckBox_10.Enable = 'off'; app.CheckBox_11.Enable = 'off'; app.CheckBox_12.Enable = 'off';
                app.CheckBox_13.Enable = 'off'; app.CheckBox_14.Enable = 'off'; app.CheckBox_15.Enable = 'off'; app.CheckBox_16.Enable = 'off';
                app.CheckBox_17.Enable = 'off'; app.CheckBox_18.Enable = 'off'; app.CheckBox_19.Enable = 'off'; app.CheckBox_20.Enable = 'off';
                app.CheckBox_21.Enable = 'off'; app.CheckBox_22.Enable = 'off'; app.CheckBox_23.Enable = 'off'; app.CheckBox_24.Enable = 'off';
                app.CheckBox_25.Enable = 'off'; app.CheckBox_26.Enable = 'off'; app.CheckBox_27.Enable = 'off'; app.CheckBox_28.Enable = 'off';
                app.CheckBox_29.Enable = 'off'; app.CheckBox_30.Enable = 'off'; app.CheckBox_31.Enable = 'off'; app.CheckBox_32.Enable = 'off';
                app.CheckBox_33.Enable = 'off'; app.CheckBox_34.Enable = 'off'; app.CheckBox_35.Enable = 'off'; app.CheckBox_36.Enable = 'off';
                app.CheckBox_37.Enable = 'off'; app.CheckBox_38.Enable = 'off'; app.CheckBox_39.Enable = 'off'; app.CheckBox_40.Enable = 'off';
                app.CheckBox_41.Enable = 'off'; app.CheckBox_42.Enable = 'off'; app.CheckBox_43.Enable = 'off'; app.CheckBox_44.Enable = 'off';
                app.CheckBox_45.Enable = 'off'; app.CheckBox_46.Enable = 'off'; app.CheckBox_47.Enable = 'off'; app.CheckBox_48.Enable = 'off';
                app.CheckBox_49.Enable = 'off'; app.CheckBox_50.Enable = 'off'; app.CheckBox_51.Enable = 'off'; app.CheckBox_52.Enable = 'off';
                app.CheckBox_53.Enable = 'off'; app.CheckBox_54.Enable = 'off'; app.CheckBox_55.Enable = 'off'; app.CheckBox_56.Enable = 'off';
                app.CheckBox_57.Enable = 'off'; app.CheckBox_58.Enable = 'off'; app.CheckBox_59.Enable = 'off'; app.CheckBox_60.Enable = 'off';
                app.CheckBox_61.Enable = 'off'; app.CheckBox_62.Enable = 'off'; app.CheckBox_63.Enable = 'off'; app.CheckBox_64.Enable = 'off';
                app.CheckBox_65.Enable = 'off'; app.CheckBox_66.Enable = 'off'; app.CheckBox_67.Enable = 'off'; app.CheckBox_68.Enable = 'off';
                app.CheckBox_69.Enable = 'off'; app.CheckBox_70.Enable = 'off'; app.CheckBox_71.Enable = 'off'; app.CheckBox_72.Enable = 'off';
                app.CheckBox_73.Enable = 'off'; app.CheckBox_74.Enable = 'off'; app.CheckBox_75.Enable = 'off'; app.CheckBox_76.Enable = 'off';
                app.CheckBox_77.Enable = 'off'; app.CheckBox_78.Enable = 'off'; app.CheckBox_79.Enable = 'off'; app.CheckBox_80.Enable = 'off';
                app.CheckBox_81.Enable = 'off'; app.CheckBox_82.Enable = 'off'; app.CheckBox_83.Enable = 'off'; app.CheckBox_84.Enable = 'off';
                app.CheckBox_85.Enable = 'off'; app.CheckBox_86.Enable = 'off'; app.CheckBox_87.Enable = 'off'; app.CheckBox_88.Enable = 'off';
                app.CheckBox_89.Enable = 'off'; app.CheckBox_90.Enable = 'off'; app.CheckBox_91.Enable = 'off'; app.CheckBox_92.Enable = 'off';
                app.CheckBox_93.Enable = 'off'; app.CheckBox_94.Enable = 'off'; app.CheckBox_95.Enable = 'off'; app.CheckBox_96.Enable = 'off';
             
            else
            
                if app.ns > 1
                    app.FirstSliceEditField.Enable = 'on';
                    app.LastSliceEditField.Enable = 'on';
                else
                    app.FirstSliceEditField.Enable = 'off';
                    app.LastSliceEditField.Enable = 'off';
                end

                if app.nd > 1
                    app.FirstDynamicEditField.Enable = 'on';
                    app.LastDynamicEditField.Enable = 'on';
                else
                    app.FirstDynamicEditField.Enable = 'off';
                    app.LastDynamicEditField.Enable = 'off';
                end

                app.Rsquare.Enable = 'on';
                app.AbortFitButton.Enable = 'on';

                if app.bartDetectedFlag && app.validDataFlag
                    app.CSrecoCheckBox.Enable = 'on';
                    app.CSrecoLabel.Enable = 'on';
                else
                    app.CSrecoCheckBox.Enable = 'off';
                    app.CSrecoLabel.Enable = 'off';
                end

                app.DeNoiseCheckBox.Enable = 'on';
                app.DeNoiseLabel.Enable = 'on';
                app.DeNoiseWindowEditField.Enable = 'on';
                app.DeRingCheckBox.Enable = 'on';
                app.DeRingLabel.Enable = 'on';

                app.WVxyzEditField.Enable = 'on';
                app.WVxyzEditFieldLabel.Enable = 'on';
                app.TVxyzEditField.Enable = 'on';
                app.TVxyzEditFieldLabel.Enable = 'on';
                app.LLRxyzEditField.Enable = 'on';
                app.LLRxyzEditFieldLabel.Enable = 'on';
                app.TVteEditField.Enable = 'on';
                app.TVteEditFieldLabel.Enable = 'on';
                app.TVdynEditField.Enable = 'on';
                app.TVdynEditFieldLabel.Enable = 'on';

                if app.bartDetectedFlag && app.validRecoFlag
                    app.ModelBasedCheckBox.Enable = 'on';
                    app.ModelBasedLabel.Enable = 'on';
                else
                    app.ModelBasedCheckBox.Enable = 'off';
                    app.ModelBasedLabel.Enable = 'off';
                end
                
                app.CheckBox_1.Enable = 'on';
                if app.ir > 1 app.CheckBox_2.Enable = 'on'; end;        if app.ir > 32 app.CheckBox_33.Enable = 'on'; end
                if app.ir > 2 app.CheckBox_3.Enable = 'on'; end;        if app.ir > 33 app.CheckBox_34.Enable = 'on'; end
                if app.ir > 3 app.CheckBox_4.Enable = 'on'; end;        if app.ir > 34 app.CheckBox_35.Enable = 'on'; end
                if app.ir > 4 app.CheckBox_5.Enable = 'on'; end;        if app.ir > 35 app.CheckBox_36.Enable = 'on'; end
                if app.ir > 5 app.CheckBox_6.Enable = 'on'; end;        if app.ir > 36 app.CheckBox_37.Enable = 'on'; end
                if app.ir > 6 app.CheckBox_7.Enable = 'on'; end;        if app.ir > 37 app.CheckBox_38.Enable = 'on'; end
                if app.ir > 7 app.CheckBox_8.Enable = 'on'; end;        if app.ir > 38 app.CheckBox_39.Enable = 'on'; end
                if app.ir > 8 app.CheckBox_9.Enable = 'on'; end;        if app.ir > 39 app.CheckBox_40.Enable = 'on'; end
                if app.ir > 9 app.CheckBox_10.Enable = 'on'; end;       if app.ir > 40 app.CheckBox_41.Enable = 'on'; end
                if app.ir > 10 app.CheckBox_11.Enable = 'on'; end;      if app.ir > 41 app.CheckBox_42.Enable = 'on'; end
                if app.ir > 11 app.CheckBox_12.Enable = 'on'; end;      if app.ir > 42 app.CheckBox_43.Enable = 'on'; end
                if app.ir > 12 app.CheckBox_13.Enable = 'on'; end;      if app.ir > 43 app.CheckBox_44.Enable = 'on'; end
                if app.ir > 13 app.CheckBox_14.Enable = 'on'; end;      if app.ir > 44 app.CheckBox_45.Enable = 'on'; end
                if app.ir > 14 app.CheckBox_15.Enable = 'on'; end;      if app.ir > 45 app.CheckBox_46.Enable = 'on'; end
                if app.ir > 15 app.CheckBox_16.Enable = 'on'; end;      if app.ir > 46 app.CheckBox_47.Enable = 'on'; end
                if app.ir > 16 app.CheckBox_17.Enable = 'on'; end;      if app.ir > 47 app.CheckBox_48.Enable = 'on'; end
                if app.ir > 17 app.CheckBox_18.Enable = 'on'; end;      if app.ir > 48 app.CheckBox_49.Enable = 'on'; end
                if app.ir > 18 app.CheckBox_19.Enable = 'on'; end;      if app.ir > 49 app.CheckBox_50.Enable = 'on'; end
                if app.ir > 19 app.CheckBox_20.Enable = 'on'; end;      if app.ir > 50 app.CheckBox_51.Enable = 'on'; end
                if app.ir > 20 app.CheckBox_21.Enable = 'on'; end;      if app.ir > 51 app.CheckBox_52.Enable = 'on'; end
                if app.ir > 21 app.CheckBox_22.Enable = 'on'; end;      if app.ir > 52 app.CheckBox_53.Enable = 'on'; end
                if app.ir > 22 app.CheckBox_23.Enable = 'on'; end;      if app.ir > 53 app.CheckBox_54.Enable = 'on'; end
                if app.ir > 23 app.CheckBox_24.Enable = 'on'; end;      if app.ir > 54 app.CheckBox_55.Enable = 'on'; end
                if app.ir > 24 app.CheckBox_25.Enable = 'on'; end;      if app.ir > 55 app.CheckBox_56.Enable = 'on'; end
                if app.ir > 25 app.CheckBox_26.Enable = 'on'; end;      if app.ir > 56 app.CheckBox_57.Enable = 'on'; end
                if app.ir > 26 app.CheckBox_27.Enable = 'on'; end;      if app.ir > 57 app.CheckBox_58.Enable = 'on'; end
                if app.ir > 27 app.CheckBox_28.Enable = 'on'; end;      if app.ir > 58 app.CheckBox_59.Enable = 'on'; end
                if app.ir > 28 app.CheckBox_29.Enable = 'on'; end;      if app.ir > 59 app.CheckBox_60.Enable = 'on'; end
                if app.ir > 29 app.CheckBox_30.Enable = 'on'; end;      if app.ir > 60 app.CheckBox_61.Enable = 'on'; end
                if app.ir > 30 app.CheckBox_31.Enable = 'on'; end;      if app.ir > 61 app.CheckBox_62.Enable = 'on'; end
                if app.ir > 31 app.CheckBox_32.Enable = 'on'; end;      if app.ir > 62 app.CheckBox_63.Enable = 'on'; end
                if app.ir > 71 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 63 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 72 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 64 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 73 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 65 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 74 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 66 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 75 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 67 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 76 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 68 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 77 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 69 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 78 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 70 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 79 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 86 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 80 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 87 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 81 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 88 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 82 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 89 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 83 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 90 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 84 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 91 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 85 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 92 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 93 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 95 app.CheckBox_64.Enable = 'on'; end
                if app.ir > 94 app.CheckBox_64.Enable = 'on'; end;      if app.ir > 96 app.CheckBox_64.Enable = 'on'; end     
            
            end
                                   
            drawnow;
            
        end
        
        
        % Switch image selection buttons on/off
        function SetImageSelectionButtons(app,code)
            
            if code == 0
                app.SlicesEditField1.Enable = 'off';
                app.SlicesSlider1.Enable = 'off';
                app.EchoesEditField.Enable = 'off';
                app.EchoesSlider.Enable = 'off';
                app.DynamicsEditField1.Enable = 'off';
                app.DynamicsSlider1.Enable = 'off';
            else
                if app.ns > 1
                    app.SlicesEditField1.Enable = 'on';
                    app.SlicesSlider1.Enable = 'on';
                else
                    app.SlicesEditField1.Enable = 'off';
                    app.SlicesSlider1.Enable = 'off';
                end
                if app.nd > 1
                    app.DynamicsEditField1.Enable = 'on';
                    app.DynamicsSlider1.Enable = 'on';
                else
                    app.DynamicsEditField1.Enable = 'off';
                    app.DynamicsSlider1.Enable = 'off';
                end
                app.EchoesEditField.Enable = 'on';
                app.EchoesSlider.Enable = 'on';
            end

            drawnow;
            
        end
        
        
        % Switch image segmentation buttons on/off
        function SetSegmentationButtons(app,code)
            
            if code == 0
                app.ThresholdEditField.Enable = 'off';
                app.ThresholdSlider.Enable = 'off';
                app.ErodeButton.Enable = 'off';
                app.DilateButton.Enable = 'off';
                app.CloseButton.Enable = 'off';
                app.OpenButton.Enable = 'off';
            else
                app.ThresholdEditField.Enable = 'on';
                app.ThresholdSlider.Enable = 'on';
                app.ErodeButton.Enable = 'on';
                app.DilateButton.Enable = 'on';
                app.CloseButton.Enable = 'on';
                app.OpenButton.Enable = 'on';
            end
            
            drawnow;
            
        end
        
        
        % Switch T1 map buttons on/off
        function SetMapButtons(app,code)
            
            if code == 0
                app.SlicesEditField2.Enable = 'off';
                app.SlicesSlider2.Enable = 'off';
                app.SlicesEditField3.Enable = 'off';
                app.SlicesSlider3.Enable = 'off';
                app.T1ScaleEditField.Enable = 'off';
                app.T1ScaleAutoButton.Enable = 'off';
                app.DynamicsEditField2.Enable = 'off';
                app.DynamicsEditField3.Enable = 'off';
                app.DynamicsSlider2.Enable = 'off';
                app.DynamicsSlider3.Enable = 'off';
                app.grayButton.Enable = 'off';
                app.autumnButton.Enable = 'off';
                app.winterButton.Enable = 'off';
                app.parulaButton.Enable = 'off';
                app.hotButton.Enable = 'off';
                app.AbortFitButton.Enable = 'off';
            else
                if app.ns > 1
                    app.SlicesEditField2.Enable = 'on';
                    app.SlicesSlider2.Enable = 'on';
                    app.SlicesEditField3.Enable = 'on';
                    app.SlicesSlider3.Enable = 'on';
                else
                    app.SlicesEditField2.Enable = 'off';
                    app.SlicesSlider2.Enable = 'off';
                    app.SlicesEditField3.Enable = 'off';
                    app.SlicesSlider3.Enable = 'off';
                end
                if app.nd > 1
                    app.DynamicsEditField2.Enable = 'on';
                    app.DynamicsEditField3.Enable = 'on';
                    app.DynamicsSlider2.Enable = 'on';
                    app.DynamicsSlider3.Enable = 'on';
                else
                    app.DynamicsEditField2.Enable = 'off';
                    app.DynamicsEditField3.Enable = 'off';
                    app.DynamicsSlider2.Enable = 'off';
                    app.DynamicsSlider3.Enable = 'off';
                end
                app.T1ScaleEditField.Enable = 'on';
                app.T1ScaleAutoButton.Enable = 'on';
                app.grayButton.Enable = 'on';
                app.autumnButton.Enable = 'on';
                app.winterButton.Enable = 'on';
                app.parulaButton.Enable = 'on';
                app.hotButton.Enable = 'on';
                app.AbortFitButton.Enable = 'on';
            end
           
            drawnow;
            
        end
        
        
        % Initialize the GUI
        function InitializeUI(app)
            
            % Message
            app.TextMessage('Initializing UI ...');

            % App version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);
            
            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.grey = '#787878';
            
            % Logo's
            app.TulpLogo.ImageSource = 'AmsterdamUMC.gif';
            app.MRSImage.ImageSource = 'MRS.gif';

             % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');
            
            % Icons
            app.LoadMRDfileButton.Icon = 'disk.png';
            app.RecoimagesButton.Icon = 'reconstruct.gif';
            app.RegisterButton.Icon = 'sort.gif';
            app.MaskButton.Icon = 'threshold.gif';
            app.FitT1Button.Icon = 'exponential.gif';
            app.ExportDCMButton.Icon = 'disk.png';
            app.ExportGIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.StatusImage.ImageSource = 'statusokay.gif';
            app.T1Image.ImageSource = 'square.gif';
            app.RawImage.ImageSource = 'square.gif';
            app.M0Image.ImageSource = 'square.gif';
            app.R2Image.ImageSource = 'square.gif';
            
            % set lights and buttons
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([1,0,0,0,0,0,0,1]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            
            % Set some plot properties
            app.RawDataFig.Toolbar.Visible = 'off';
            app.T1MapFig.Toolbar.Visible = 'off';
            app.M0MapFig.Toolbar.Visible = 'off';
            app.T1linearPlot.Toolbar.Visible = 'off';
            app.t1cmap = parula;
            app.m0cmap = gray;
            app.r2cmap = parula;
            app.t1cmap(1,:) = [0 0 0];
            app.m0cmap(1,:) = [0 0 0];
            app.r2cmap(1,:) = [0 0 0];
            app.parulaButton.Value = 1;
            app.grayButton2.Value = 1;
            app.parulaButton3.Value = 1;
            
            % Switch off plot interactivity
            app.RawDataFig.Interactions = [];
            app.T1MapFig.Interactions = [];
            app.M0MapFig.Interactions = [];
            app.T1linearPlot.Interactions = [];
            
            % ROis and cross-hair
            app.roiFlag = false;
            app.roiPoints = [];
            delete(app.roiT1);
            
            drawnow;
            
        end


        %------------------------------------------------
        %
        %  Figures and Plots
        %
        %------------------------------------------------


        % Displays the mask in the raw image data
        function PlotImageFig(app)

            if app.validRecoFlag

                cla(app.RawDataFig);
                hold(app.RawDataFig,'on');

                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
                cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
                cmap2 = [linspace(0,1,128)', zeros(128,2)];
                cmap = [cmap1;cmap2];
                cmap(cmap > 1) = 1;

                % Background anatomy image
                im = squeeze(app.images(app.EchoesEditField.Value,:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end

                % Scale
                if app.AutoLWButton.Value == 1
                    maxIm = max(im(:));
                    app.WindowEditField.Value = maxIm;
                    app.WindowSlider.Value = maxIm;
                    app.LevelEditField.Value = maxIm/2;
                    app.LevelSlider.Value = maxIm/2;
                end

                % Window and Level
                im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;

                % Plot the background im
                xlim(app.RawDataFig,[0, size(im,1) + 1]);
                ylim(app.RawDataFig,[0, size(im,2) + 1]);
                background = imshow(rot90(im),[0,2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig); %#ok<NASGU>

                % Overlay image with the mask
                imo = squeeze(2-app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value));
                alpha = squeeze(0.7-0.7*app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value));
                if app.parameters.PHASE_ORIENTATION
                    imo = rot90(imo,-1);
                    alpha = rot90(alpha,-1);
                end
                overlay = imshow(rot90(imo),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawDataFig);
                set(overlay,'AlphaData',rot90(alpha));

                % Image aspect ratio
                [~, dx, dy, ~, ~] = size(app.images);
                aratio = app.AspectRatioViewField.Value*dx/dy;
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.RawDataFig,[1/aratio 1 1]);
                else
                    daspect(app.RawDataFig,[aratio 1 1]);
                end

                hold(app.RawDataFig,'off');
                drawnow;

                % Set some limits now
                maxim = app.images(1,:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value);
                app.ThresholdEditField.Limits = [0 max(maxim(:))+1];
                app.ThresholdEditField.Value = app.threshold(app.SlicesEditField1.Value,app.DynamicsEditField1.Value);
                app.ThresholdSlider.Limits = [0 max(maxim(:))+1];
                app.ThresholdSlider.Value = app.threshold(app.SlicesEditField1.Value,app.DynamicsEditField1.Value);

            end

        end % PlotRawImages
        
        
        % Plots the maps for the current slice
        function PlotMaps(app)

            if app.validFitFlag

                hold(app.T1MapFig,'on');
                hold(app.M0MapFig,'on');
                hold(app.R2MapFig,'on');

                % Plot the T1 map
                im = squeeze(app.t1map(:,:,app.SlicesEditField2.Value,app.DynamicsEditField2.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.T1MapFig,[0, size(im,1) + 1]);
                ylim(app.T1MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 app.T1ScaleEditField.Value],'Colormap',app.t1cmap,'InitialMagnification','fit','Parent',app.T1MapFig);
                aratio = app.AspectRatioViewField.Value*size(im,2)/size(im,1);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.T1MapFig,[1/aratio 1 1]);
                else
                    daspect(app.T1MapFig,[aratio 1 1]);
                end
                colorbar(app.T1MapFig,'east','Ticks',[]);

                % ROI display if present
                if app.roiFlag && ~isempty(app.roiPoints)
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==app.SlicesEditField2.Value, :);
                    if app.parameters.PHASE_ORIENTATION
                        ysize = app.dimx/app.oversample;
                    else
                        ysize = app.dimy;
                    end
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);
                    app.CalculateRoiT1;
                end

                % Plot the M0 map
                m0scale = round(2*mean(nonzeros(squeeze(app.m0map(:,:,app.SlicesEditField3.Value,app.DynamicsEditField3.Value)))));
                if isnan(m0scale) m0scale = 100; end
                im = squeeze(app.m0map(:,:,app.SlicesEditField3.Value,app.DynamicsEditField3.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.M0MapFig,[0, size(im,1) + 1]);
                ylim(app.M0MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 m0scale],'Colormap',app.m0cmap,'InitialMagnification','fit','Parent',app.M0MapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.M0MapFig,[1/aratio 1 1]);
                else
                    daspect(app.M0MapFig,[aratio 1 1]);
                end

                % Plot the R2 map
                im = squeeze(app.r2map(:,:,app.SlicesEditField3.Value,app.DynamicsEditField3.Value));
                if app.parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                xlim(app.R2MapFig,[0, size(im,1) + 1]);
                ylim(app.R2MapFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[app.Rsquare.Value-0.01 1],'Colormap',app.r2cmap,'InitialMagnification','fit','Parent',app.R2MapFig);
                if app.parameters.PHASE_ORIENTATION
                    daspect(app.R2MapFig,[1/aratio 1 1]);
                else
                    daspect(app.R2MapFig,[aratio 1 1]);
                end
                colorbar(app.R2MapFig,'east','Ticks',[app.Rsquare.Value-0.01, 1]);
                delete(app.txt1);
                delete(app.txt2);
                app.txt1 = text(app.R2MapFig,0.8,0.95,'1.0','Units','normalized','Color',app.grey,'FontSize',14);
                app.txt2 = text(app.R2MapFig,0.8,0.05,num2str(app.Rsquare.Value),'Units','normalized','Color',app.grey,'FontSize',14);

                hold(app.T1MapFig,'off');
                hold(app.M0MapFig,'off');
                hold(app.R2MapFig,'off');
                drawnow;

            end
            
        end % PlotMaps


        % Draw a cross-hair on the T1 map
        function PlotCross(app,xcor,ycor,ysize)

            if app.validFitFlag

                delete(app.crossT1);
                delete(app.crossM0);

                clr1 = 'r';
                if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                    clr1 = 'g';
                end

                clr2 = 'r';
                if app.autumnButton2.Value == 1 || app.hotButton2.Value == 1
                    clr2 = 'g';
                end

                app.crossT1 = drawcrosshair(app.T1MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr1, 'LineWidth', 1);
                app.crossM0 = drawcrosshair(app.M0MapFig, 'Position', [xcor ysize-ycor+1], 'Color', clr2, 'LineWidth', 1);

            end
        
        end % PlotCross


        % Draw points on the T1 map
        function PlotPoint(app,xcor,ycor,ysize)

            if app.validFitFlag

                clr1 = 'r';
                if app.autumnButton.Value == 1 || app.hotButton.Value == 1
                    clr1 = 'g';
                end

                clr2 = 'r';
                if app.autumnButton2.Value == 1 || app.hotButton2.Value == 1
                    clr2 = 'g';
                end

                hold(app.T1MapFig,'on');
                delete(app.roiT1);
                app.roiT1 = plot(app.T1MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr1, 'MarkerFaceColor', clr1);
                hold(app.T1MapFig,'off');

                hold(app.M0MapFig,'on');
                delete(app.roiM0);
                app.roiM0 = plot(app.M0MapFig, xcor, ysize-ycor+1, 's', 'MarkerSize', 4, 'Color', clr2, 'MarkerFaceColor', clr2);
                hold(app.M0MapFig,'off');

            end

        end % PlotPoint
        

        % Calculate and show T1 values of ROI
        function CalculateRoiT1(app)

            if app.validFitFlag

                try

                    % ysize needed
                    if app.parameters.PHASE_ORIENTATION
                        ysize = app.dimx/app.oversample;
                    else
                        ysize = app.dimy;
                    end

                    % Mask the raw data
                    maskedImages = zeros(size(app.images));
                    for i = 1:size(app.images,1)
                        maskedImages(i,:,:,:,:) = squeeze(app.images(i,:,:,:,:)).*app.mask(:,:,:,:);
                    end

                    % Extract T1, M0 and data values from the map
                    for i = 1:size(app.roiPoints,1)
                        if app.parameters.PHASE_ORIENTATION
                            t1values(i) = app.t1map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            m0values(i) = app.m0map(ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            rawdatas(i,:) = maskedImages(:,ysize-app.roiPoints(i,2)+1,app.roiPoints(i,1),app.roiPoints(i,3),app.DynamicsEditField2.Value)'; %#ok<AGROW>
                        else
                            t1values(i) = app.t1map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            m0values(i) = app.m0map(app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.DynamicsEditField2.Value); %#ok<AGROW>
                            rawdatas(i,:) = maskedImages(:,app.roiPoints(i,1),app.roiPoints(i,2),app.roiPoints(i,3),app.DynamicsEditField2.Value)'; %#ok<AGROW>
                        end
                    end

                    % Averages in ROI or cross-hair value
                    r1values = 1./t1values;
                    r1values = nonzeros(r1values);
                    r1values(isnan(r1values)) = [];
                    r1values(isinf(r1values)) = [];
                    t1value = 1/mean(r1values);                         % T1 = 1 / mean(R1 values in pixels)
                    stdt1value = t1value*std(r1values)/mean(r1values);  % error propagation with division
                    if size(rawdatas,1)>1
                        rawdatas(rawdatas==0) = nan;
                        app.roiIntData = mean(rawdatas,'omitnan');             % trick to exclude ROI pixels in background
                    else
                        app.roiIntData = rawdatas;
                    end

                    % TI selection
                    app.MakeTiArray;
                    nrTIs = length(find(app.tiSelection(1:app.ir) == 1));

                    % Do a fit of the ROI-averaged pixel intensities
                    if nrTIs>1
                        [A,B,m0roi,t1roi,~] = dotheT1fit_single(app.roiIntData, app.tis, app.Rsquare.Value, app.tiSelection);
                    else
                        t1roi = 0;
                    end

                    % Plot the results of the fits in the chosen pixel
                    app.PlotFits(app.tis,app.roiIntData,t1value,t1roi,stdt1value,m0roi,A,B);

                catch

                    app.TextMessage('Error determining T1 in ROI ...')

                end

            end

        end % CalculateRoiT1

        
        % Plots the fitresults in the 2 fit-windows
        function PlotFits(app,irTimes,data,t1,t1roi,stdt1,m0,Aroi,Broi)
            
          if app.validRecoFlag  
              
            cla(app.T1linearPlot);
            hold(app.T1linearPlot,'on');
            
            % Colormap for datapoints
            cmap = [hex2rgb(app.red) ; hex2rgb(app.blue)];
            ticolorflag = cmap(app.tiSelection + 1,:);
            ticolorflag = ticolorflag(1:app.ir,:);
            
            if (t1roi>0) && (m0>0)
                
                % Fit
                x = 0 : 1 : max(irTimes)+5;
                t1RoiStar = t1roi/(Broi/Aroi-1);
                s = Aroi - Broi.*exp(-x./t1RoiStar);

                % Determine negative signal intensities
                datac = data;
                factor = -log(Aroi/Broi)*t1RoiStar;
                for cnt = 1:length(data)
                    if app.tis(cnt) < factor
                        datac(cnt) = -datac(cnt);
                    end
                end

                % Save the sign corrected data
                app.roiIntDataSigned = datac;
                
                % Plot 
                dataPoints = scatter(app.T1linearPlot,app.tis,datac,[],ticolorflag,'filled');
                plot(app.T1linearPlot,x,s,'LineWidth',3);
                uistack(dataPoints, 'top');
                xlim(app.T1linearPlot,[0, max(irTimes)+5]);
                ylim(app.T1linearPlot,[1.1*min(s),1.2*max(s)]);
              
                % Show T1 value in app
                if stdt1 > 0
                    app.T1ViewField.Value = [num2str(t1,'% 10.1f'),' ',char(177),' ',num2str(stdt1,'% 10.1f')];
                else
                    app.T1ViewField.Value = num2str(t1,'% 10.1f');
                end

                app.T1ViewField2.Value = num2str(t1roi,'% 10.1f');
            
            else

                app.T1ViewField.Value = " ";
                app.T1ViewField2.Value = " ";

            end
            
            hold(app.T1linearPlot,'off');
            
          end
           
        end % PlotFits


        %------------------------------------------------
        %
        %  Parameters and settings
        %
        %------------------------------------------------


        % Sets global parameters
        function SetParameters(app)
           
            % Message
            app.TextMessage('Setting scan parameters ...');
            
            % sequence parameters
            app.ir = app.parameters.NO_ECHOES;
            app.dimx = app.parameters.NO_SAMPLES;
            app.dimy = app.parameters.NO_VIEWS;
            app.fov = app.parameters.FOV;
            app.ns = app.parameters.NO_SLICES;
            app.nd = app.parameters.EXPERIMENT_ARRAY;
            app.tr = app.parameters.tr;
            app.ti = app.parameters.ti;
            app.sl = app.parameters.SLICE_THICKNESS;
            app.tis = app.ti + app.tr*app.parameters.lines_per_segment*linspace(0,app.ir-1,app.ir)'; % inversion times
            app.TextMessage(strcat("Inversion times = ",num2str(app.tis')));
            app.oversample = 1;
            if app.parameters.oversample == 1
                app.oversample = 2;
            end
            
            % show parameters & set limits
            app.TRViewField.Value = app.tr;
            app.TI0EditField.Value = app.ti;
            app.TISEditField.Value = app.tis(end);
            app.EchoesViewField.Value = app.ir;
            app.DynamicsViewField.Value = app.nd;
            app.dimxViewField.Value = app.dimx;
            app.dimyViewField.Value = app.dimy;
            app.SlicesViewField.Value = app.ns;
            app.SlicesEditField1.Value = 1;
            app.SlicesSlider1.Value = 1;
            app.SlicesEditField2.Value = 1;
            app.SlicesSlider2.Value = 1;
            app.SlicesEditField3.Value = 1;
            app.SlicesSlider3.Value = 1;
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditField.Value = 1;
            if app.ns > 1
                app.SlicesEditField1.Limits = [1, app.ns];
                app.SlicesSlider1.Limits = [1, app.ns];
                app.SlicesEditField2.Limits = [1, app.ns];
                app.SlicesSlider2.Limits = [1, app.ns];
                app.SlicesEditField3.Limits = [1, app.ns];
                app.SlicesSlider3.Limits = [1, app.ns];
                app.FirstSliceEditField.Limits = [1, app.ns];
                app.LastSliceEditField.Limits = [1, app.ns];
            else
                app.SlicesEditField1.Limits = [1, 2];
                app.SlicesSlider1.Limits = [1, 2];
                app.SlicesEditField2.Limits = [1, 2];
                app.SlicesSlider2.Limits = [1, 2];
                app.SlicesEditField3.Limits = [1, 2];
                app.SlicesSlider3.Limits = [1, 2];
                app.FirstSliceEditField.Limits = [1, 2];
                app.LastSliceEditField.Limits = [1, 2];
            end
            app.DynamicsEditField1.Value = 1;
            app.DynamicsEditField2.Value = 1;
            app.DynamicsEditField3.Value = 1;
            app.DynamicsSlider1.Value = 1;
            app.DynamicsSlider2.Value = 1;
            app.DynamicsSlider3.Value = 1;
            if app.nd > 1
                app.DynamicsEditField1.Limits  = [1, app.nd];
                app.DynamicsEditField2.Limits  = [1, app.nd];
                app.DynamicsEditField3.Limits  = [1, app.nd];
                app.DynamicsSlider1.Limits  = [1, app.nd];
                app.DynamicsSlider2.Limits  = [1, app.nd];
                app.DynamicsSlider3.Limits  = [1, app.nd];
            else
                app.DynamicsEditField1.Limits  = [1, 2];
                app.DynamicsEditField2.Limits  = [1, 2];
                app.DynamicsEditField3.Limits  = [1, 2];
                app.DynamicsSlider1.Limits  = [1, 2];
                app.DynamicsSlider2.Limits  = [1, 2];
                app.DynamicsSlider3.Limits  = [1, 2];
            end
            app.EchoesViewField.Value = app.ir;
            app.EchoesEditField.Limits = [1, app.ir];
            app.EchoesSlider.Limits = [1, app.ir];
            app.SLTViewField.Value = app.sl;
            app.FOVViewField.Value = app.fov;
            app.AspectRatioViewField.Value = app.parameters.FOVf/8;
            app.FirstSliceEditField.Value = 1;
            app.LastSliceEditField.Value = app.ns;
            app.FirstDynamicEditField.Value = 1;
            app.LastDynamicEditField.Value = app.nd;
            app.abortFitFlag = false;

            % Uncheck/reset some options
            app.CSrecoCheckBox.Value = 0;
            app.DeNoiseCheckBox.Value = 0;
            app.DeNoiseWindowEditField.Value = 8;
            app.DeRingCheckBox.Value = 0;
            app.ModelBasedCheckBox.Value = 0;

            % File tag
            [~,app.tag,~] = fileparts(app.dataFile);

            % Window and level
            app.WindowEditField.Value = 32767;
            app.LevelEditField.Value = 16384;
            app.WindowSlider.Value = 32767;
            app.LevelSlider.Value = 16384;
            app.AutoLWButton.Value = 1;

            % Segmentation
            app.structuringElement2D = strel("disk",1);
            app.roiPoints = [];
            
            % Define empty m0, t1map, r2map
            app.m0map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
            app.t1map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
            app.r2map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                        
            % Define mask initially with ones
            app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);

            % Reset treshold values
            app.threshold = zeros(app.ns,app.nd);
            
        end % SetParameters
    
        
        % make TE array
        function MakeTiArray(app)
            
            app.tiSelection = [...
                app.CheckBox_1.Value  app.CheckBox_2.Value  app.CheckBox_3.Value  app.CheckBox_4.Value ...
                app.CheckBox_5.Value  app.CheckBox_6.Value  app.CheckBox_7.Value  app.CheckBox_8.Value ...
                app.CheckBox_9.Value  app.CheckBox_10.Value app.CheckBox_11.Value app.CheckBox_12.Value ...
                app.CheckBox_13.Value app.CheckBox_14.Value app.CheckBox_15.Value app.CheckBox_16.Value ...
                app.CheckBox_17.Value app.CheckBox_18.Value app.CheckBox_19.Value app.CheckBox_20.Value ...
                app.CheckBox_21.Value app.CheckBox_22.Value app.CheckBox_23.Value app.CheckBox_24.Value ...
                app.CheckBox_25.Value app.CheckBox_26.Value app.CheckBox_27.Value app.CheckBox_28.Value ...
                app.CheckBox_29.Value app.CheckBox_30.Value app.CheckBox_31.Value app.CheckBox_32.Value ...
                app.CheckBox_33.Value app.CheckBox_34.Value app.CheckBox_35.Value app.CheckBox_36.Value ...
                app.CheckBox_37.Value app.CheckBox_38.Value app.CheckBox_39.Value app.CheckBox_40.Value ...
                app.CheckBox_41.Value app.CheckBox_42.Value app.CheckBox_43.Value app.CheckBox_44.Value ...
                app.CheckBox_45.Value app.CheckBox_46.Value app.CheckBox_47.Value app.CheckBox_48.Value ...
                app.CheckBox_49.Value app.CheckBox_50.Value app.CheckBox_51.Value app.CheckBox_52.Value ...
                app.CheckBox_53.Value app.CheckBox_54.Value app.CheckBox_55.Value app.CheckBox_56.Value ...
                app.CheckBox_57.Value app.CheckBox_58.Value app.CheckBox_59.Value app.CheckBox_60.Value ...
                app.CheckBox_61.Value app.CheckBox_62.Value app.CheckBox_63.Value app.CheckBox_64.Value ...
                app.CheckBox_65.Value app.CheckBox_66.Value app.CheckBox_67.Value app.CheckBox_68.Value ...
                app.CheckBox_69.Value app.CheckBox_70.Value app.CheckBox_71.Value app.CheckBox_72.Value ...
                app.CheckBox_73.Value app.CheckBox_74.Value app.CheckBox_75.Value app.CheckBox_76.Value ...
                app.CheckBox_77.Value app.CheckBox_78.Value app.CheckBox_79.Value app.CheckBox_80.Value ...
                app.CheckBox_81.Value app.CheckBox_82.Value app.CheckBox_83.Value app.CheckBox_84.Value ...
                app.CheckBox_85.Value app.CheckBox_86.Value app.CheckBox_87.Value app.CheckBox_88.Value ...
                app.CheckBox_89.Value app.CheckBox_90.Value app.CheckBox_91.Value app.CheckBox_92.Value ...
                app.CheckBox_93.Value app.CheckBox_94.Value app.CheckBox_95.Value app.CheckBox_96.Value ...
                ];
            app.tiSelection = [app.tiSelection ones(1,1024)];
            
        end
        
        % Set default values for which echoes to include in fitting 
        function SetInversionRecovery(app)
            
                app.CheckBox_1.Value = 0; app.CheckBox_2.Value = 1; app.CheckBox_3.Value = 1; app.CheckBox_4.Value = 1; 
                app.CheckBox_5.Value = 1; app.CheckBox_6.Value = 1; app.CheckBox_7.Value = 1; app.CheckBox_8.Value = 1; 
                app.CheckBox_9.Value = 1; app.CheckBox_10.Value = 1; app.CheckBox_11.Value = 1; app.CheckBox_12.Value = 1; 
                app.CheckBox_13.Value = 1; app.CheckBox_14.Value = 1; app.CheckBox_15.Value = 1; app.CheckBox_16.Value = 1; 
                app.CheckBox_17.Value = 1; app.CheckBox_18.Value = 1; app.CheckBox_19.Value = 1; app.CheckBox_20.Value = 1; 
                app.CheckBox_21.Value = 1; app.CheckBox_22.Value = 1; app.CheckBox_23.Value = 1; app.CheckBox_24.Value = 1; 
                app.CheckBox_25.Value = 1; app.CheckBox_26.Value = 1; app.CheckBox_27.Value = 1; app.CheckBox_28.Value = 1; 
                app.CheckBox_29.Value = 1; app.CheckBox_30.Value = 1; app.CheckBox_31.Value = 1; app.CheckBox_32.Value = 1; 
                app.CheckBox_33.Value = 1; app.CheckBox_34.Value = 1; app.CheckBox_35.Value = 1; app.CheckBox_36.Value = 1; 
                app.CheckBox_37.Value = 1; app.CheckBox_38.Value = 1; app.CheckBox_39.Value = 1; app.CheckBox_40.Value = 1; 
                app.CheckBox_41.Value = 1; app.CheckBox_42.Value = 1; app.CheckBox_43.Value = 1; app.CheckBox_44.Value = 1; 
                app.CheckBox_45.Value = 1; app.CheckBox_46.Value = 1; app.CheckBox_47.Value = 1; app.CheckBox_48.Value = 1; 
                app.CheckBox_49.Value = 1; app.CheckBox_50.Value = 1; app.CheckBox_51.Value = 1; app.CheckBox_52.Value = 1; 
                app.CheckBox_53.Value = 1; app.CheckBox_54.Value = 1; app.CheckBox_55.Value = 1; app.CheckBox_56.Value = 1; 
                app.CheckBox_57.Value = 1; app.CheckBox_58.Value = 1; app.CheckBox_59.Value = 1; app.CheckBox_60.Value = 1; 
                app.CheckBox_61.Value = 1; app.CheckBox_62.Value = 1; app.CheckBox_63.Value = 1; app.CheckBox_64.Value = 1;
                app.CheckBox_65.Value = 1; app.CheckBox_66.Value = 1; app.CheckBox_67.Value = 1; app.CheckBox_68.Value = 1; 
                app.CheckBox_69.Value = 1; app.CheckBox_70.Value = 1; app.CheckBox_71.Value = 1; app.CheckBox_72.Value = 1; 
                app.CheckBox_73.Value = 1; app.CheckBox_74.Value = 1; app.CheckBox_75.Value = 1; app.CheckBox_76.Value = 1; 
                app.CheckBox_77.Value = 1; app.CheckBox_78.Value = 1; app.CheckBox_79.Value = 1; app.CheckBox_80.Value = 1; 
                app.CheckBox_81.Value = 1; app.CheckBox_82.Value = 1; app.CheckBox_83.Value = 1; app.CheckBox_84.Value = 1; 
                app.CheckBox_85.Value = 1; app.CheckBox_86.Value = 1; app.CheckBox_87.Value = 1; app.CheckBox_88.Value = 1; 
                app.CheckBox_89.Value = 1; app.CheckBox_90.Value = 1; app.CheckBox_91.Value = 1; app.CheckBox_92.Value = 1; 
                app.CheckBox_93.Value = 1; app.CheckBox_94.Value = 1; app.CheckBox_95.Value = 1; app.CheckBox_96.Value = 1;
        end


    end % methods


    % ----------------------------------------------
    %
    % Public functions, available outside the app
    %
    % ----------------------------------------------


    methods (Access = public)


        % Text messages in the app
        function TextMessage(app, message)
            
            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end
            
        end % TextMessage
        
        
        % Status icon
        function SetStatus(app,status)
            
            switch status
                
                case 0
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.statusLabel.Text = 'OK';
                    app.statusLabel.FontColor = app.green;
                    
                case 1
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.statusLabel.Text = 'warning';
                    app.statusLabel.FontColor = app.orange;
                    sound(app.warningSound,48000);
                    
                case 2
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.statusLabel.Text = 'error';
                    app.statusLabel.FontColor = app.brightred;
                    sound(app.errorSound,48000);
                case 3
                    app.StatusImage.ImageSource = 'statuswait.gif';
                    app.statusLabel.Text = 'busy';
                    app.statusLabel.FontColor = 'black';
                    
            end
            
            drawnow;
            
        end


    end % methods (Access = public)


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            
            % disable unnecessary warnings
            clc;
            warning off;
            
            % main window noname
            app.T1mappUIFigure.Name = '';
            
            % Start the parallel pool if it is not running already
            try
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Trick to automatically include the dll file during compilation
            try
                if ispc
                    import('libfftw3-3.dll');
                end
            catch
            end
            
            % empty file import and export paths
            app.mrdImportPath = '';
            app.dicomExportPath = '';
            app.gifExportPath = '';
            app.roiExportPath = '';
            app.mrdFlag = true;
            app.mobaFlag = false;
            app.abortFitFlag = false;
            app.abortRegFlag = false;
                      
            % add subfolders to search path
            if (~isdeployed)
                app.oldDir = pwd;
                info = dir(which('LLT1mapp.mlapp'));
                app.appDir = info.folder;
                cd(app.appDir);
                addpath(genpath(pwd));
                try
                    rmpath(genpath(strcat(pwd,filesep,'LLT1mapp/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'LLT1mapp_resources/')));
                catch
                end
                try
                    rmpath(genpath(strcat(pwd,filesep,'Standalone installers/')));
                catch
                end
            end
            
            % Initialize user interface
            app.InitializeUI;
            
            % Add the bart path to the environment variable if available
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            app.bartDetectedFlag = false;
            try
                bart(app,'version');
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Report whether BART is available or not
            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Elastix
            try
                if ismac || isunix
                    [~, result] = system('echo -n $PATH');
                    if ~contains(result,':/usr/local/bin:/usr/local/lib')
                        result = [result ':/usr/local/bin:/usr/local/lib'];
                        setenv('PATH', result);
                    end
                else
                    [elastixDir, ~] = fileparts(which('elastix.exe'));
                    result = getenv('PATH');
                    if ~contains(result,elastixDir)
                        result = [result ';' elastixDir];
                        setenv('PATH', result);
                    end
                end
            catch
            end

            % Trick to automatically include the exe and dll file during compilation
            try
                if ispc
                    import('elastix.exe');
                end
            catch
            end
            try
                if ispc
                    import('ANNlib-5.1.dll');
                end
            catch
            end

        end

        % Button pushed function: LoadMRDfileButton
        function LoadMRDfileButtonPushed(app, event)
            
            % Load the inversion recovery T1 data file
            
            % Indicate busy
            app.SetLights([0,0,0,0,0,0,0]);
            app.SetButtons([0,0,0,0,0,0,0,0]);
            app.SetFitButtons(0);
            app.SetImageSelectionButtons(0);
            app.SetSegmentationButtons(0);
            app.SetMapButtons(0);
            app.SetStatus(3);
            
            % check standard smis directory first if platform is pc, only 1x per session
            if ispc
                try
                    folder_name = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folder_name = [folder_name,filesep];
                    app.TextMessage('MRD directory found in Windows registry ...');
                    app.TextMessage(strcat('MRD directory =',{' '},folder_name));
                catch 
                    app.TextMessage('Trying standard MRD directory ...');
                    folder_name = 'c:\smis\dev\MRD\';
                end
                if exist(folder_name, 'dir') && app.mrdFlag
                    app.mrdImportPath = folder_name;
                    app.mrdFlag = false;
                end
            end

            try

                % Reset flags
                app.validDataFlag = false;
                app.validRecoFlag = false;
                app.validRegFlag = false;
                app.validFitFlag = false;
                app.multiCoilFlag = false;

                % Select a MRD file
                app.T1mappUIFigure.Visible = 'off';
                [mrdfile,app.mrdImportPath] = uigetfile(strcat(app.mrdImportPath,filesep,'*.MRD'));
                app.T1mappUIFigure.Visible = 'on';

                if ~ischar(mrdfile)
                    ME = MException('TLL1mapp:UserCancel','User cancel ...');
                    throw(ME);
                end

                % MRD file selected
                app.dataFile = [app.mrdImportPath,mrdfile];
                mrdstart = extractBefore(mrdfile,'_');
                mrdfile = [app.mrdImportPath,mrdfile];
                flist = dir(fullfile(app.mrdImportPath,[mrdstart,'*.MRD']));

                % check wether the filelist contains already a reconstructed MRD file, remove this one from the list
                if length(flist) > 1
                    for i = length(flist):-1:1
                        if contains(flist(i).name,'retro') || contains(flist(i).name,'p2roud')
                            flist(i) = [];
                        end
                    end
                end

                if isempty(flist)
                    ME = MException('TLL1mapp:NoValidFile','ERROR: Please select a valid Look-Locker data file ...');
                    throw(ME);
                end

                % check for mulit-receiver-coil data
                app.nrCoils = length(flist);
                if app.nrCoils>1
                    app.multiCoilFlag = true;
                    app.TextMessage('Multi receiver coil data detected ...');
                end

                % load the data
                app.TextMessage('Loading k-space data ...');
                data1 = {};
                for i=1:app.nrCoils
                    app.TextMessage(strcat('Loading coil',{' '},num2str(i)));
                    [data1{i},app.dimensions,app.parameters] = Get_mrd_3D4(fullfile(flist(i).folder,flist(i).name),'seq','cen'); %#ok<AGROW>
                end

                % Valid file
                if isfield(app.parameters,'NO_ECHOES') && isfield(app.parameters,'prep_on') && isfield(app.parameters,'ti')
                    if app.parameters.NO_ECHOES > 1 && app.parameters.prep_on == 1 && app.parameters.ti > 0
                        app.TextMessage('Multiple images detected ...');
                        app.DatafileEditField.Value = mrdfile;
                        app.dataType = "IR";
                    else
                        ME = MException('TLL1mapp:NoValidFile','ERROR: Please select a valid Look-Locker data file ...');
                        throw(ME);
                    end
                end

                % Data = (echoes, dimx, dimy, dimz, dynamics)
                app.data = {};
                for i=1:app.nrCoils

                    if ndims(data1{i}) == 3 && app.parameters.EXPERIMENT_ARRAY == 1
                        % Single slice, single dynamic data
                        app.data{i}(:,:,:,1,1) = permute(data1{i}(:,:,:),[1 3 2]);

                    elseif ndims(data1{i}) == 4 && app.parameters.EXPERIMENT_ARRAY > 1
                        % Single slice, multi dynamic data
                        app.data{i}(:,:,:,1,:) = permute(data1{i}(:,:,:,:),[2 4 3 1]);

                    elseif ndims(data1{i}) == 4 && app.parameters.EXPERIMENT_ARRAY == 1
                        % Multi slice data, single dynamic data
                        app.data{i}(:,:,:,:,1) = permute(data1{i},[1,4,3,2]);

                    elseif ndims(data1{i}) == 5 && app.parameters.EXPERIMENT_ARRAY > 1
                        % Multi slice data, single dynamic data
                        app.data{i}(:,:,:,:,:) = permute(data1{i},[2,5,4,3,1]);

                    else
                        ME = MException('TLL1mapp:NoValidFile','ERROR: Please select a valid Look-Locker data file ...');
                        throw(ME);
                    end

                end

                % Orientation
                if isfield(app.parameters,'PHASE_ORIENTATION')
                    if app.parameters.PHASE_ORIENTATION
                        app.TextMessage('INFO: phase orientation = 1 / horizontal ... ');
                    else
                        app.TextMessage('INFO: phase orientation = 0 / vertical ... ')
                    end
                end

                % Segmented k-space
                if isfield(app.parameters,'lines_per_segment')
                    if app.parameters.lines_per_segment > 1
                        app.TextMessage('Segmented k-space ... ')
                        for i=1:app.nrCoils
                            app.data{i} = sort2DsegmKspaceMRD(app.data{i},app.parameters);
                        end
                    end
                end

                % 3D data not supported
                if isfield(app.parameters,'NO_VIEWS_2')
                    if app.parameters.NO_VIEWS_2 > 1
                        ME = MException('TLL1mapp:NoValidFile','ERROR: 3D data currently not supported ...');
                        throw(ME);
                    end
                end

                % Success
                app.validDataFlag = true;
                app.SetParameters;
                app.RecoimagesButtonPushed;

            catch ME

                % Failure
                app.TextMessage(ME.message);
                app.mrdImportPath = '';
                app.validDataFlag = false;
                app.SetFitButtons(0);
                app.SetLights([0,0,0,0,0,0,0]);
                app.SetButtons([1,0,0,0,0,0,0,1]);
                app.SetStatus(2);

            end

        end

        % Button pushed function: RecoimagesButton
        function RecoimagesButtonPushed(app, event)
            
            if app.validDataFlag

                % Indicate busy
                app.SetLights([1,0,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TabGroup2.SelectedTab = app.RawimagesTab;

                % reset some parameters
                app.SlicesEditField1.Value = 1;
                app.SlicesSlider1.Value = 1;
                app.EchoesEditField.Value = 1;
                app.EchoesSlider.Value = 1;
                app.ThresholdEditField.Value = 1;
                app.ThresholdSlider.Value = 1;
                app.SlicesEditField2.Value = 1;
                app.SlicesSlider2.Value = 1;
                app.SlicesEditField3.Value = 1;
                app.SlicesSlider3.Value = 1;
                app.DynamicsEditField1.Value = 1;
                app.DynamicsEditField2.Value = 1;
                app.DynamicsEditField3.Value = 1;
                app.DynamicsSlider1.Value = 1;
                app.DynamicsSlider2.Value = 1;
                app.DynamicsSlider3.Value = 1;

                % reset m0 and t1map
                app.m0map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                app.t1map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                app.r2map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);

                try

                    if app.bartDetectedFlag && (app.CSrecoCheckBox.Value == 1)

                        % Perform the BART compressed sensing reconstruction
                        app.TextMessage('CS reconstruction ...');

                        app.images = CSreco(app);

                    else

                        % Perform regular FFT reconstruction
                        app.TextMessage('FFT reconstruction ...');

                        app.images = FFTreco(app);

                    end

                    % Readout oversampling
                    if isfield(app.parameters,"oversample")
                        if app.parameters.oversample == 1
                            app.TextMessage('Readout oversampling ...');
                            os = round(size(app.images,2)/4);
                            app.images = app.images(:,os+1:end-os,:,:,:);
                        end
                    end

                    % Denoising
                    if app.DeNoiseCheckBox.Value
                        app.TextMessage('PCA denoising ...');
                        app.images = PCAdenoise(app.images,app.DeNoiseWindowEditField.Value);
                    end

                    % Gibbs ringing suppression
                    if app.DeRingCheckBox.Value
                        app.TextMessage('Gibbs ringing suppression ...');
                        app.images = unRing(app.images);
                    end

                    % Normalize to convenient range
                    app.images = app.images*32767/max(app.images(:));

                    % Success
                    app.TextMessage('Ready ...');
                    app.validRecoFlag = true;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    
                    % Show the images
                    PlotImageFig(app);
                    PlotMaps(app);

                    % Reset buttons
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.validRecoFlag = false;
                    app.validRegFlag = false;
                    app.validFitFlag = false;
                    app.SetLights([1,0,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,1,0,0,1]);
                    app.SetStatus(2);

                end

            end
                
        end

        % Value changed function: TI0EditField, TISEditField
        function TEViewFieldValueChanged(app, event)
            
            app.te = app.TI0EditField.Value;
            app.parameters.te = app.TI0EditField.Value;
            app.esp = app.TISEditField.Value;
            app.tis = app.te + app.esp*linspace(0,app.ir-1,app.ir)'; % echo times
            app.TextMessage(strcat('Echo times = ',{' '},num2str(app.tis')));
            
        end

        % Value changed function: DynamicsEditField1, EchoesEditField, 
        % ...and 1 other component
        function SlicesEditFieldValue1Changed(app, event)
            
            % Adjust the slider
            app.SlicesSlider1.Value = app.SlicesEditField1.Value;
            app.EchoesSlider.Value = app.EchoesEditField.Value;
            app.DynamicsSlider1.Value = app.DynamicsEditField1.Value;
        
            % Plot the raw image 
            PlotImageFig(app);
            
        end

        % Value changed function: SlicesSlider1
        function SlicesSlider1ValueChanged(app, event)
            
            % Adjust the slices edit field value
            app.SlicesEditField1.Value = round(app.SlicesSlider1.Value);
            
            % Continue with the changed slices edit field callback
            SlicesEditFieldValue1Changed(app, event)
            
        end

        % Value changed function: EchoesSlider
        function EchoesSliderValueChanged(app, event)
            
            % Adjust the echoes edit field value
            app.EchoesEditField.Value = round(app.EchoesSlider.Value);
            
            % Continue with the changed slices / echoes edit field callback
            SlicesEditFieldValue1Changed(app, event)
            
        end

        % Value changed function: DynamicsSlider1
        function DynamicsSlider1ValueChanged(app, event)
          
            % Adjust the echoes edit field value
            app.DynamicsEditField1.Value = round(app.DynamicsSlider1.Value);
            
            % Continue with the changed slices / echoes edit field callback
            SlicesEditFieldValue1Changed(app, event)
            
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)

            if app.validRecoFlag

                % Indicate busy
                app.SetLights([1,1,0,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.AbortRegButton.Enable = 'on';
                app.SetMapButtons(0);
                app.SetStatus(3);
            
                try

                    % Register all slices to 1st inversion time image
                    registerImages(app);

                    % Reset m0 and t1map
                    app.m0map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.t1map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.r2map = zeros(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                    app.threshold = zeros(1,app.ns,app.nd);

                    % Replot the images
                    PlotImageFig(app);

                    % Success
                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.validFitFlag = false;
                    app.SetLights([1,1,1,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,0,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);

                    app.SetImageSelectionButtons(1);
                    app.SetFitButtons(1);
                    app.AbortRegButton.Enable = 'off';
                    app.validFitFlag = false;
                    app.SetLights([1,1,0,0,0,0,0]);
                    app.SetButtons([1,1,1,1,0,0,0,1]);
                    app.SetStatus(2);
   
                end

            end
            
        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)
            
            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
            
            if app.validRecoFlag
                
                % Indicate busy
                app.SetLights([2,2,2,0,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);
                app.TextMessage('Thresholding images ....');
                
                % Determine automatic threshold using the Otsu method for each slice and each dynamic
                for dynamic = 1:app.nd
                    for slice = 1:app.ns
                        maxim = squeeze(app.images(1,:,:,slice,dynamic));
                        app.threshold(slice,dynamic) = graythresh(mat2gray(app.images(1,:,:,slice,dynamic))) * max(maxim(:));
                    end
                end
                
                % Set the automatic mask for all slices
                app.mask = ones(app.dimx/app.oversample,app.dimy,app.ns,app.nd);
                for dynamic = 1:app.nd
                    for slice=1:app.ns
                        app.mask(:,:,slice,dynamic) = apply_threshold_t1(squeeze(app.images(1,:,:,slice,dynamic)),app.threshold(slice,dynamic));
                    end
                end
                
                % Display the threshold value for the current slice
                app.ThresholdEditField.Value = app.threshold(app.SlicesEditField1.Value,app.DynamicsEditField1.Value);
                
                % Display the mask in the image
                PlotImageFig(app);
                
                % Indicate busy
                app.SetLights([2,2,2,1,0,0,0]);
                app.SetButtons([1,1,1,1,1,0,0,1]);
                app.SetFitButtons(1);
                app.SetImageSelectionButtons(1);
                app.SetSegmentationButtons(1);
                app.SetMapButtons(0);
                app.SetStatus(0);
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            % Get the current slice and dynamic
            cslice = app.SlicesEditField1.Value;
            cdynamic = app.DynamicsEditField1.Value;
            
            % Change the value of the threshold for this slice and update the slider
            app.threshold(cslice,cdynamic) = app.ThresholdEditField.Value;
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;
            
            % Apply the new threshold
            app.mask(:,:,cslice,cdynamic) = apply_threshold_t1(squeeze(app.images(1,:,:,cslice,cdynamic)),app.threshold(cslice,cdynamic));
           
            % Display the result;
            PlotImageFig(app)
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            % Threshold slider changed
            % Adjust Threshold and call threshold change button
            app.ThresholdEditField.Value = round(app.ThresholdSlider.Value);
            ThresholdEditFieldValueChanged(app, event);
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            % Morphological erosion on current slice
            app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value) = 1-imerode(1-squeeze(app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value)),app.structuringElement2D);

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
        
            % Morphological dilation on current slice
            app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value) = 1-imdilate(1-squeeze(app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value)),app.structuringElement2D);

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            
            % Morphological dilation on current slice
            app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value) = 1-imdilate(1-squeeze(app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value)),app.structuringElement2D);
            app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value) = 1-imerode(1-squeeze(app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value)),app.structuringElement2D);

            % Display the result;
            PlotImageFig(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Morphological dilation on current slice
            app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value) = 1-imopen(1-squeeze(app.mask(:,:,app.SlicesEditField1.Value,app.DynamicsEditField1.Value)),app.structuringElement2D);

            % Display the result;
            PlotImageFig(app);
            
        end

        % Value changed function: FirstSliceEditField, LastSliceEditField
        function FirstSliceEditFieldValueChanged(app, event)
            
            app.FirstSliceEditField.Value(app.FirstSliceEditField.Value > app.ti) = app.ti;
            app.LastSliceEditField.Value(app.LastSliceEditField.Value > app.ti) = app.ti;

            if app.FirstSliceEditField.Value > app.LastSliceEditField.Value
                app.LastSliceEditField.Value = app.FirstSliceEditField.Value;
            end
            
        end

        % Value changed function: FirstDynamicEditField, 
        % ...and 1 other component
        function FirstDynamicEditFieldValueChanged(app, event)
            
            app.LastDynamicEditField.Value(app.LastDynamicEditField.Value > app.nd) = app.nd;
            app.FirstDynamicEditField.Value(app.FirstDynamicEditField.Value > app.nd) = app.nd;
            
            if app.FirstDynamicEditField.Value > app.LastDynamicEditField.Value
                app.LastDynamicEditField.Value = app.FirstDynamicEditField.Value;
            end
            
        end

        % Button pushed function: FitT1Button
        function FitT1ButtonPushed(app, event)
            
            if app.validRecoFlag && (app.ti > 0)

                app.SetLights([2,2,2,2,0,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(1);
                app.SetStatus(3);
                app.TextMessage('Fitting T1 maps ....');
                app.FitProgressGauge.Value = 0;
                app.validFitFlag = false;
                app.abortFitFlag = false;
                drawnow;

                try

                    % TI selection
                    app.MakeTiArray;
                    nrTIs = length(find(app.tiSelection(1:app.ir) == 1));

                    if nrTIs < 3
                        ME = MException('LLT1mapp:TIerror','ERROR: Number of TIs should be at least 3 for fitting ...');
                        throw(ME);
                    end

                    app.TextMessage("T1 map fitting ...");

                    % Progress counter reset
                    cnt = 1;
                    totalNumberOfSteps = (app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1)*...
                        (app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1);

                    % Timing parameters
                    app.EstimatedFitTimeViewField.Value = 'Calculating ...';
                    elapsedTime = 0;

                    % For selected dynamics
                    dynamic = app.FirstDynamicEditField.Value - 1;

                    while dynamic < app.LastDynamicEditField.Value && ~app.abortFitFlag

                        dynamic = dynamic + 1;

                        % for selected slices
                        slice = app.FirstSliceEditField.Value - 1;

                        while slice < app.LastSliceEditField.Value && ~app.abortFitFlag

                            slice = slice + 1;

                            tic;

                            app.TextMessage(sprintf("Fitting slice %d of %d ...",slice-app.FirstSliceEditField.Value+1,app.LastSliceEditField.Value-app.FirstSliceEditField.Value+1));
                            app.TextMessage(sprintf("Fitting dynamic %d of %d ...",dynamic-app.FirstDynamicEditField.Value+1,app.LastDynamicEditField.Value-app.FirstDynamicEditField.Value+1));
                            drawnow;

                            % Do the T1 fitting
                            if app.bartDetectedFlag && (app.ModelBasedCheckBox.Value == 1)
                                % Model-based fitting
                                [app.m0map(:,:,slice,dynamic),app.t1map(:,:,slice,dynamic)] = dothemobaT1fit(app,slice,dynamic);
                                app.r2map(:,:,slice,dynamic) = zeros(size(squeeze(app.r2map(:,:,slice,dynamic))));
                            else
                                % Regular least-squares regression
                                [app.m0map(:,:,slice,dynamic),app.t1map(:,:,slice,dynamic),app.r2map(:,:,slice,dynamic)] = dotheT1fit_slice(squeeze(app.images(:,:,:,slice,dynamic)), squeeze(app.mask(:,:,slice,dynamic)), app.tis, app.Rsquare.Value, app.tiSelection);
                            end

                            % Auto scale
                            try
                                scale = round(2.5*mean(nonzeros(abs(app.t1map(:).*app.mask(:)))));
                                scale(scale<5)=5;
                            catch
                                scale = 100;
                            end
                            app.T1ScaleEditField.Value = scale;

                            % Plot the maps thusfar
                            app.validFitFlag = true;
                            PlotMaps(app);
                            app.validFitFlag = false;

                            % Update progresss bar
                            app.FitProgressGauge.Value = round(100 * cnt/totalNumberOfSteps);
                            drawnow;

                            % Update the timing indicator
                            elapsedTime = elapsedTime + toc;
                            estimatedtotaltime = elapsedTime * totalNumberOfSteps / cnt;
                            timeRemaining = estimatedtotaltime * (totalNumberOfSteps - cnt) / totalNumberOfSteps;
                            timeRemaining(timeRemaining<0) = 0;
                            app.EstimatedFitTimeViewField.Value = strcat(datestr(seconds(timeRemaining),'MM:SS')," min:sec"); %#ok<*DATST>
                            drawnow;

                            cnt = cnt + 1;

                        end

                    end

                    % Success
                    app.validFitFlag = true;
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetFitButtons(1);
                    app.SetLights([1,1,2,1,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);

                    app.validFitFlag = false;
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetFitButtons(1);
                    app.SetLights([2,2,2,2,1,0,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)
            
            app.abortFitFlag = true; 
        
        end

        % Window scroll wheel function: T1mappUIFigure
        function T1MappUIFigureWindowScrollWheel(app, event)

            if app.ns > 1

                verticalScrollCount = event.VerticalScrollCount;

                % Get the Position of the GUI and the position of the mouse
                set(0,'units','pixels');
                guiposition = get(app.T1mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');
                Xpos = cursorposition(1)-guiposition(1);

                if Xpos < 820

                    if app.validRecoFlag

                        if verticalScrollCount > 0
                            newslice = app.SlicesEditField1.Value + 1;
                            newslice(newslice>app.SlicesEditField1.Limits(2)) = app.SlicesEditField1.Limits(2);
                        else
                            newslice = app.SlicesEditField1.Value - 1;
                            newslice(newslice<1) = 1;
                        end

                        app.SlicesEditField1.Value = newslice;
                        app.SlicesEditFieldValue1Changed;

                    end

                else

                    if ~app.roiFlag

                        if app.validFitFlag

                            if verticalScrollCount > 0
                                newslice = app.SlicesEditField2.Value + 1;
                                newslice(newslice>app.SlicesEditField2.Limits(2)) = app.SlicesEditField2.Limits(2);
                            else
                                newslice = app.SlicesEditField2.Value - 1;
                                newslice(newslice<1) = 1;
                            end

                            app.SlicesEditField2.Value = newslice;
                            app.SlicesEditField3.Value = newslice;
                            app.SlicesEditField2ValueChanged;
                            app.SlicesEditField3ValueChanged;

                        end

                    end

                end

                drawnow;

            end

        end

        % Value changed function: SlicesEditField2
        function SlicesEditField2ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.SlicesSlider2.Value = round(app.SlicesEditField2.Value);
            app.SlicesEditField3.Value = app.SlicesEditField2.Value;
            app.SlicesSlider3.Value = app.SlicesSlider2.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: SlicesSlider2
        function SlicesSlider2ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.SlicesEditField2.Value = round(app.SlicesSlider2.Value);
            app.SlicesEditField3.Value = round(app.SlicesSlider2.Value);
            app.SlicesSlider3.Value = app.SlicesSlider2.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: SlicesEditField3
        function SlicesEditField3ValueChanged(app, event)
            
            % Change the slider value according to the edit number
            app.SlicesSlider3.Value = round(app.SlicesEditField3.Value);
            app.SlicesEditField2.Value = app.SlicesEditField3.Value;
            app.SlicesSlider2.Value = app.SlicesSlider3.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: SlicesSlider3
        function SlicesSlider3ValueChanged(app, event)
            
            % Change the Slice nr value according to the slider
            app.SlicesEditField3.Value = round(app.SlicesSlider3.Value);
            app.SlicesEditField2.Value = round(app.SlicesSlider3.Value);
            app.SlicesSlider2.Value = app.SlicesSlider3.Value;
            
            % Plot the T1-map for the new slice
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsEditField2
        function DynamicsEditField2ValueChanged(app, event)
            
            % Change the dynamics values according to the edit number
            app.DynamicsSlider2.Value = app.DynamicsEditField2.Value;
            app.DynamicsSlider3.Value = app.DynamicsEditField2.Value;
            app.DynamicsEditField3.Value = app.DynamicsEditField2.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsSlider2
        function DynamicsSlider2ValueChanged(app, event)
            
            % Change the dynamics nr value according to the slider
            app.DynamicsEditField2.Value = round(app.DynamicsSlider2.Value);
            app.DynamicsEditField3.Value = round(app.DynamicsSlider2.Value);
            app.DynamicsSlider3.Value = app.DynamicsSlider2.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsEditField3
        function DynamicsEditField3ValueChanged(app, event)
                        
            % Change the dynamics values according to the edit number
            app.DynamicsSlider3.Value = app.DynamicsEditField3.Value;
            app.DynamicsSlider2.Value = app.DynamicsEditField3.Value;
            app.DynamicsEditField2.Value = app.DynamicsEditField3.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: DynamicsSlider3
        function DynamicsSlider3ValueChanged(app, event)
                        
            % Change the dynamics nr value according to the slider
            app.DynamicsEditField3.Value = round(app.DynamicsSlider3.Value);
            app.DynamicsEditField2.Value = round(app.DynamicsSlider3.Value);
            app.DynamicsSlider2.Value = app.DynamicsSlider3.Value;
            
            % Plot the T1-map for the new dynamic
            PlotMaps(app);
            
        end

        % Value changed function: T1ScaleEditField
        function T1ScaleEditFieldValueChanged(app, event)
            
            % Plot the T1 map with the new scale
            PlotMaps(app);
            
        end

        % Button pushed function: T1ScaleAutoButton
        function T1ScaleAutoButtonPushed(app, event)
            
            % Estimate a suitable scale for the T1 map
            % Auto-scale = 2 x mean of the T1 values in the image
            scale = round(2*mean(nonzeros(squeeze(app.t1map(:,:,app.SlicesEditField2.Value,app.DynamicsEditField2.Value))))); 
            scale = 100*ceil(scale/100);
            scale(isnan(scale)) = 2500;
            scale(scale>5000) = 5000;
            scale(scale<5)=5;

            app.T1ScaleEditField.Value = scale;
            
            % Plot the map
            PlotMaps(app);
            
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged2(app, event)
            
            if app.grayButton.Value == 1 
                app.t1cmap = gray; 
            end
            
            if app.autumnButton.Value == 1
                app.t1cmap = autumn; 
            end
            
            if app.winterButton.Value == 1
                app.t1cmap = winter; 
            end
            
            if app.parulaButton.Value == 1
                app.t1cmap = parula; 
            end
            
            if app.hotButton.Value == 1
                app.t1cmap = hot; 
            end
            
            % Background should always be black
            app.t1cmap(1,:) = [0 0 0];
            
            % Plot the T1 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup2
        function ColorMapButtonGroup2SelectionChanged(app, event)
            
            if app.grayButton2.Value == 1 
                app.m0cmap = gray; 
            end
            
            if app.autumnButton2.Value == 1
                app.m0cmap = autumn; 
            end
            
            if app.winterButton2.Value == 1
                app.m0cmap = winter; 
            end
            
            if app.parulaButton2.Value == 1
                app.m0cmap = parula; 
            end
            
            if app.hotButton2.Value == 1
                app.m0cmap = hot; 
            end
            
            % Background should always be black
            app.m0cmap(1,:) = [0 0 0];
            
            % Plot the M0 map with the new colormap
            PlotMaps(app);
            
        end

        % Selection changed function: ColorMapButtonGroup3
        function ColorMapButtonGroup3SelectionChanged(app, event)
              
            if app.grayButton3.Value == 1 
                app.r2cmap = gray; 
            end
            
            if app.autumnButton3.Value == 1
                app.r2cmap = autumn; 
            end
            
            if app.winterButton3.Value == 1
                app.r2cmap = winter; 
            end
            
            if app.parulaButton3.Value == 1
                app.r2cmap = parula; 
            end
            
            if app.hotButton3.Value == 1
                app.r2cmap = hot; 
            end
            
            % Background should always be black
            app.r2cmap(1,:) = [0 0 0];
            
            % Plot the R2 map with the new colormap
            PlotMaps(app);
            
            
        end

        % Image clicked function: T1Image
        function T1ImageClicked(app, event)

            if app.validFitFlag

                % Get the Position of the GUI and the position of the mouse click
                set(0,'units','pixels');
                guiposition = get(app.T1mappUIFigure,'Position');
                cursorposition = get(0,'PointerLocation');

                % Position of the cursor click in the image window
                imagepos = [862,412]; % position of bottom-left corner of image in the app (862,412)
                Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                % Image aspect ratio
                if app.parameters.PHASE_ORIENTATION
                    aratio = app.AspectRatioViewField.Value*(app.dimy/app.dimx);
                    xsize = app.dimy;
                    ysize = app.dimx/app.oversample;
                else
                    aratio = 1/(app.AspectRatioViewField.Value*(app.dimy/app.dimx));
                    xsize = app.dimx/app.oversample;
                    ysize = app.dimy;
                end

                % Correct for non-square image
                imsize = 313;
                if aratio >= 1
                    xcor = (Xpos/imsize) * xsize;
                    ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                else
                    xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                    ycor = (Ypos/imsize) * ysize;
                end

                % Round to nearest integer
                xcor = round(xcor);
                ycor = round(ycor);

                % Current slice
                zcor = app.SlicesEditField2.Value;

                % Keep the values within matrix boundaries
                xcor(xcor<1) = 1;
                ycor(ycor<1) = 1;
                xcor(xcor>xsize) = xsize;
                ycor(ycor>ysize) = ysize;
                app.XEditField.Value = xcor;
                app.YEditField.Value = ycor;

                % ROI or cross-hair display
                if app.roiFlag

                    % Include points within Radius
                    se = strel("disk",app.BrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.BrushEditField.Value-1,app.BrushEditField.Value-1];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            app.roiPoints = [app.roiPoints; [xcor+nzc(i,1) ycor+nzc(i,2) zcor]];
                        end
                    end
                    app.roiPoints = unique(app.roiPoints,'rows');
                    zColumn = app.roiPoints(:,3);
                    plotPoints = app.roiPoints(zColumn==zcor, :);
                    app.PlotPoint(plotPoints(:,1),plotPoints(:,2),ysize);

                else

                    % Plot a cross
                    app.roiPoints = [xcor ycor zcor];
                    app.PlotCross(app.roiPoints(1),app.roiPoints(2),ysize);

                end

                % Show the resulting T1 values and fits
                app.CalculateRoiT1;

            end
               
        end

        % Image clicked function: M0Image
        function M0ImageClicked(app, event)
            
            app.T1ImageClicked;

        end

        % Button pushed function: ROIbutton, ROIbutton2
        function ROIbuttonButtonPushed(app, event)
             
            % Toggle between ROI drawing and cross-hair drawing
             
             if strcmp(app.ROIbutton.Text,'ROI')
                 
                 app.ROIbutton.Text = 'Cross';
                 app.ROIbutton2.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.EraseButton.Visible = 'on';
                 app.EraseButton2.Visible = 'on';
                 app.BrushEditField.Visible = 'on';
                 app.BrushEditField2.Visible = 'on';
                 app.T1msLabel2.Visible = 'on';
                 app.T1ViewField2.Visible = 'on';
                 app.pixelValueLabel.Text = 'Pixel averaged';
                 app.ROIaveragedLabel.Visible = 'on';
                 app.roiFlag = true;
              
             else  
                 
                 app.ROIbutton.Text = 'ROI';
                 app.ROIbutton2.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.EraseButton.Visible = 'off';
                 app.EraseButton2.Visible = 'off';
                 app.BrushEditField.Visible = 'off';
                 app.BrushEditField2.Visible = 'off';
                 app.T1msLabel2.Visible = 'off';
                 app.T1ViewField2.Visible = 'off';
                 app.pixelValueLabel.Text = 'Pixel value';
                 app.ROIaveragedLabel.Visible = 'off';
                 app.roiFlag = false;
                 
             end
            
             app.roiPoints = [];
             delete(app.roiT1);
             delete(app.crossT1);
             app.PlotMaps;
             
        end

        % Button pushed function: EraseButton
        function EraseButtonPushed(app, event)
            
            app.roiPoints = [];
            delete(app.roiT1);
            app.PlotMaps;
            
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)

            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)

            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)

            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)

            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImageFig;

        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)

            if app.AutoLWButton.Value == 1
                app.PlotImageFig;
            end

        end

        % Value changed function: BrushEditField
        function BrushEditFieldValueChanged(app, event)

            app.BrushEditField2.Value = app.BrushEditField.Value;

        end

        % Value changed function: BrushEditField2
        function BrushEditField2ValueChanged(app, event)

            app.BrushEditField.Value = app.BrushEditField2.Value;
            
        end

        % Button pushed function: ExportRoiDataButton
        function ExportRoiDataButtonPushed(app, event)

            try

                if app.validFitFlag && ~isempty(app.roiPoints)

                    app.T1mappUIFigure.Visible = 'off';
                    app.roiExportPath = uigetdir(app.roiExportPath,'Raw data export path ...');
                    app.T1mappUIFigure.Visible = 'on';

                    if ~ischar(app.roiExportPath)
                        app.roiExportPath = '';
                        ME = MException('T1mapp:userCancel','User cancel ...');
                        throw(ME);
                    end

                    rdata = [app.tis app.roiIntDataSigned'];
                    writematrix(rdata,strcat(app.roiExportPath,filesep,'roiData.csv'));
                    app.TextMessage('Exporting image intensities from ROI ...');

                end

                app.SetStatus(0);

            catch ME

                app.TextMessage(ME.message);
                app.SetStatus(2);

            end

        end

        % Button pushed function: ExportDCMButton
        function ExportDCMButtonPushed(app, event)
            
            if app.validFitFlag

                % Indicate busy
                app.SetLights([1,1,2,1,1,0,2]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    % Search for dicom file
                    app.TextMessage('DICOM export ...');

                    % Assuming that MRD and Data directory share the same base-directory
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSpath{1} = replace(app.mrdImportPath,"MRD","Data");
                    try
                        k = strfind(dcmSpath{1},"Data");
                        fs = strfind(dcmSpath{1}(k:end),filesep);
                        dcmSpath{1}= dcmSpath{1}(1:k+fs(3)-1);
                        dcmSpath{1} = insertAfter(dcmSpath{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSpath{1} = app.mrdImportPath;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSpath{2} = strcat(dcmSpath{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSpath{3} = strcat(dcmSpath{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSpath{4} = strcat(dcmSpath{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the directories contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSpath)
                        if ~isempty(dir(strcat(dcmSpath{i},"*.dcm")))
                            dcmPath = dcmSpath{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmPath));
                            filesepIdx = strfind(dcmPath, filesep);
                            app.dicomExportPath = dcmPath(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        % Dicom export using Dicom information
                        try
                            % Check if directory is actually writable
                            mkdir(strcat(app.dicomExportPath,filesep,"testdir"));
                            rmdir(strcat(app.dicomExportPath,filesep,"testdir"));
                        catch
                            app.T1mappUIFigure.Visible = 'off';
                            app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                            app.T1mappUIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.dicomExportPath)
                                ME = MException('T1mapp:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end
                        app.TextMessage(strcat("DICOM export directory = ",app.dicomExportPath));
                        app.TextMessage('Export with DICOM information ...');
                        export_dicom_t1_dcm(app.dicomExportPath,dcmPath,app.m0map,app.t1map,app.r2map,app.parameters);

                    else

                        % Ask for dicom export directory
                        app.T1mappUIFigure.Visible = 'off';
                        app.dicomExportPath = uigetdir(app.dicomExportPath,'DICOM export path');
                        app.T1mappUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dicomExportPath)
                            ME = MException('T1mapp:userCancel','User cancel ...');
                            throw(ME);
                        end

                        % Dicom export without dicom information
                        dicomExportDir = strcat(app.dicomExportPath,filesep,"DICOM",filesep);
                        app.TextMessage(strcat("DICOM export directory = ",dicomExportDir));
                        export_dicom_t1(dicomExportDir,app.m0map,app.t1map,app.r2map,app.parameters,app.tag);
                        app.TextMessage('WARNING: Export without DICOM information ...');

                    end

                    % Success
                    app.SetStatus(0);
                    app.SetLights([1,1,2,1,1,1,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);


                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during DICOM export ...');
                    app.dicomExportPath = '';
                    app.SetStatus(2);
                    app.SetLights([1,1,2,1,1,0,2]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);

                end

            end

        end

        % Button pushed function: ExportGIFButton
        function ExportGIFButtonPushed(app, event)
  
            if app.validFitFlag

                % Indicate busy
                app.SetLights([1,1,2,1,1,0,0]);
                app.SetButtons([0,0,0,0,0,0,0,0]);
                app.SetFitButtons(0);
                app.SetImageSelectionButtons(0);
                app.SetSegmentationButtons(0);
                app.SetMapButtons(0);
                app.SetStatus(3);

                try

                    try
                        gifExportDir = replace(app.mrdImportPath, "MRD", "Data");
                        k = strfind(gifExportDir,"Data");
                        fs = strfind(gifExportDir(k:end),filesep);
                        gifExportDir = gifExportDir(1:k+fs(2)-1);
                        % Check if directory is actually writable
                        mkdir(strcat(gifExportDir,filesep,"testdir"));
                        rmdir(strcat(gifExportDir,filesep,"testdir"));
                    catch
                        app.T1mappUIFigure.Visible = 'off';
                        app.gifExportPath = uigetdir(app.gifExportPath,'GIF export path');
                        app.T1mappUIFigure.Visible = 'on';
                        if ~ischar(app.gifExportPath)
                            ME = MException('T1map:userCancel','User cancel ...');
                            throw(ME);
                        end
                        gifExportDir = app.gifExportPath;
                    end
                    gifExportDir = strcat(gifExportDir,filesep,"GIF",filesep);

                    % Export to animated gif
                    app.TextMessage('GIF export ...');
                    app.TextMessage(strcat("Gif export directory = ",gifExportDir));

                    export_gif_t1(gifExportDir,app.t1map,app.m0map,app.r2map,app.tag,app.T1ScaleEditField.Value,app.t1cmap,app.m0cmap,app.r2cmap,app.AspectRatioViewField.Value,app.parameters,app.Rsquare.Value);

                    % Success
                    app.TextMessage('GIF export ready ...');
                    app.SetLights([1,1,2,1,1,2,1]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.TextMessage('ERROR: Something went wrong during GIF export ...');
                    app.gifExportPath = '';
                    app.SetLights([1,1,2,1,1,2,0]);
                    app.SetButtons([1,1,1,1,1,1,1,1]);
                    app.SetFitButtons(1);
                    app.SetImageSelectionButtons(1);
                    app.SetSegmentationButtons(1);
                    app.SetMapButtons(1);
                    app.SetStatus(2);

                end

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
         
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('LLT1mapp.mlapp'));
                app.appDir = info.folder;
                rmpath(genpath(app.appDir));
                cd(app.oldDir);
            end
            
            % Exit
            app.delete;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T1mappUIFigure = uifigure('Visible', 'off');
            app.T1mappUIFigure.Name = 'LLT1mapp';
            app.T1mappUIFigure.Icon = fullfile(pathToMLAPP, 'T1icon.png');
            app.T1mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T1MappUIFigureWindowScrollWheel, true);
            app.LookLockerTMappingLabel = uilabel(app.T1mappUIFigure);
            app.LookLockerTMappingLabel.Text = 'Look-Locker T  Mapping';
            app.CreditsLabel = uilabel(app.T1mappUIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version 1.0.0';
            app.TulpLogo = uiimage(app.T1mappUIFigure);
            app.MessageBox = uitextarea(app.T1mappUIFigure);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ....'};
            app.BackgroundBox = uitextarea(app.T1mappUIFigure);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.T1mappUIFigure);
            app.statusLabel = uilabel(app.T1mappUIFigure);
            app.statusLabel.Text = 'status';
            app.MRSImage = uiimage(app.T1mappUIFigure);
            app.TabGroup1 = uitabgroup(app.T1mappUIFigure);
            app.DashboardTab = uitab(app.TabGroup1);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDfileButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDfileButtonPushed, true);
            app.LoadMRDfileButton.Enable = 'off';
            app.LoadMRDfileButton.Tooltip = {'Load MRD data'};
            app.LoadMRDfileButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.RecoimagesButton = uibutton(app.DashboardTab, 'push');
            app.RecoimagesButton.ButtonPushedFcn = createCallbackFcn(app, @RecoimagesButtonPushed, true);
            app.RecoimagesButton.Enable = 'off';
            app.RecoimagesButton.Tooltip = {'Reconstruct images'};
            app.RecoimagesButton.Text = 'Reco images';
            app.Lamp2 = uilamp(app.DashboardTab);
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Image masking to exclude background pixels'};
            app.MaskButton.Text = 'Mask';
            app.Lamp4 = uilamp(app.DashboardTab);
            app.FitT1Button = uibutton(app.DashboardTab, 'push');
            app.FitT1Button.ButtonPushedFcn = createCallbackFcn(app, @FitT1ButtonPushed, true);
            app.FitT1Button.Enable = 'off';
            app.FitT1Button.Tooltip = {'Start the T1 fitting'};
            app.FitT1Button.Text = 'Fit T1';
            app.Lamp5 = uilamp(app.DashboardTab);
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.Lamp6 = uilamp(app.DashboardTab);
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Enable = 'off';
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = 'Exit';
            app.ExportDCMButton = uibutton(app.DashboardTab, 'push');
            app.ExportDCMButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMButtonPushed, true);
            app.ExportDCMButton.Enable = 'off';
            app.ExportDCMButton.Tooltip = {'Export to DICOM'};
            app.ExportDCMButton.Text = 'Export DCM';
            app.FitProgressLabel = uilabel(app.DashboardTab);
            app.FitProgressLabel.Text = 'Progress';
            app.Lamp7 = uilamp(app.DashboardTab);
            app.ExportGIFButton = uibutton(app.DashboardTab, 'push');
            app.ExportGIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.ExportGIFButton.Enable = 'off';
            app.ExportGIFButton.Tooltip = {'Export to GIF'};
            app.ExportGIFButton.Text = 'Export GIF';
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Tooltip = {'Abort the T1 fitting'};
            app.AbortFitButton.Text = 'Abort';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Image registration'};
            app.RegisterButton.Text = 'Register';
            app.Lamp3 = uilamp(app.DashboardTab);
            app.RegProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegProgressGauge.MajorTicks = [0 25 50 75 100];
            app.RegProgressLabel = uilabel(app.DashboardTab);
            app.RegProgressLabel.Text = 'Progress';
            app.ModelBasedCheckBox = uicheckbox(app.DashboardTab);
            app.ModelBasedCheckBox.Enable = 'off';
            app.ModelBasedCheckBox.Tooltip = {'Check for model-based fitting'};
            app.ModelBasedCheckBox.Text = '';
            app.ModelBasedLabel = uilabel(app.DashboardTab);
            app.ModelBasedLabel.Enable = 'off';
            app.ModelBasedLabel.Tooltip = {'Model-based fitting using the Bart toolbox'};
            app.ModelBasedLabel.Text = 'Model-based';
            app.CSrecoCheckBox = uicheckbox(app.DashboardTab);
            app.CSrecoCheckBox.Enable = 'off';
            app.CSrecoCheckBox.Tooltip = {'Check for compressed sensing reconstruction'};
            app.CSrecoCheckBox.Text = '';
            app.CSrecoLabel = uilabel(app.DashboardTab);
            app.CSrecoLabel.Enable = 'off';
            app.CSrecoLabel.Tooltip = {'Compressed sensing reconstruction'};
            app.CSrecoLabel.Text = 'CS reco';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Registration image transformation method'};
            app.RegistrationDropDown.Value = 'Affine';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort registration'};
            app.AbortRegButton.Text = 'Abort';
            app.TabGroup3 = uitabgroup(app.T1mappUIFigure);
            app.T1mapTab = uitab(app.TabGroup3);
            app.T1mapTab.Title = 'T1-map';
            app.T1MapFig = uiaxes(app.T1mapTab);
            app.T1MapFig.XTick = [];
            app.T1MapFig.YTick = [];
            app.T1linearPlot = uiaxes(app.T1mapTab);
            xlabel(app.T1linearPlot, 'TI')
            ylabel(app.T1linearPlot, 'Signal')
            app.T1MapLabel = uilabel(app.T1mapTab);
            app.T1MapLabel.Text = 'T1 map';
            app.ImageselectionPanel_2 = uipanel(app.T1mapTab);
            app.ImageselectionPanel_2.Title = 'Image selection';
            app.SlicesSlider2 = uislider(app.ImageselectionPanel_2);
            app.SlicesSlider2.Limits = [1 200];
            app.SlicesSlider2.MajorTicks = [];
            app.SlicesSlider2.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider2ValueChanged, true);
            app.SlicesSlider2.MinorTicks = [];
            app.SlicesSlider2.Tooltip = {'Slice number slider'};
            app.SlicesSlider2.Value = 1;
            app.SliceEditField_2Label = uilabel(app.ImageselectionPanel_2);
            app.SliceEditField_2Label.Text = 'Slice';
            app.SlicesEditField2 = uieditfield(app.ImageselectionPanel_2, 'numeric');
            app.SlicesEditField2.Limits = [1 Inf];
            app.SlicesEditField2.ValueDisplayFormat = '%.0f';
            app.SlicesEditField2.ValueChangedFcn = createCallbackFcn(app, @SlicesEditField2ValueChanged, true);
            app.SlicesEditField2.Tooltip = {'Slice number'};
            app.SlicesEditField2.Value = 1;
            app.DynamicLabel_2 = uilabel(app.ImageselectionPanel_2);
            app.DynamicLabel_2.Text = 'Dynamic';
            app.DynamicsEditField2 = uieditfield(app.ImageselectionPanel_2, 'numeric');
            app.DynamicsEditField2.Limits = [1 Inf];
            app.DynamicsEditField2.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField2.ValueChangedFcn = createCallbackFcn(app, @DynamicsEditField2ValueChanged, true);
            app.DynamicsEditField2.Tooltip = {'Dynamic number'};
            app.DynamicsEditField2.Value = 1;
            app.DynamicsSlider2 = uislider(app.ImageselectionPanel_2);
            app.DynamicsSlider2.Limits = [1 200];
            app.DynamicsSlider2.MajorTicks = [];
            app.DynamicsSlider2.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider2ValueChanged, true);
            app.DynamicsSlider2.MinorTicks = [];
            app.DynamicsSlider2.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider2.Value = 1;
            app.ColormapButtonGroup = uibuttongroup(app.T1mapTab);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged2, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.autumnButton = uiradiobutton(app.ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.winterButton = uiradiobutton(app.ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.parulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Value = true;
            app.hotButton = uiradiobutton(app.ColormapButtonGroup);
            app.hotButton.Text = 'hot';
            app.T1ScaleEditField = uieditfield(app.ColormapButtonGroup, 'numeric');
            app.T1ScaleEditField.Limits = [1 Inf];
            app.T1ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @T1ScaleEditFieldValueChanged, true);
            app.T1ScaleEditField.Tooltip = {'T1 map scale'};
            app.T1ScaleEditField.Value = 1;
            app.T1ScaleMaxLabel = uilabel(app.ColormapButtonGroup);
            app.T1ScaleMaxLabel.Text = 'Max';
            app.T1ScaleAutoButton = uibutton(app.ColormapButtonGroup, 'push');
            app.T1ScaleAutoButton.ButtonPushedFcn = createCallbackFcn(app, @T1ScaleAutoButtonPushed, true);
            app.T1ScaleAutoButton.Tooltip = {'Automatically scale T1 map'};
            app.T1ScaleAutoButton.Text = 'Auto';
            app.T1Image = uiimage(app.T1mapTab);
            app.T1Image.ImageClickedFcn = createCallbackFcn(app, @T1ImageClicked, true);
            app.XEditFieldLabel = uilabel(app.T1mapTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.T1mapTab, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Editable = 'off';
            app.XEditField.Tooltip = {'X coordinate'};
            app.YEditFieldLabel = uilabel(app.T1mapTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.T1mapTab, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Editable = 'off';
            app.YEditField.Tooltip = {'Y coordinate'};
            app.ROIbutton = uibutton(app.T1mapTab, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton.Text = 'ROI';
            app.EraseButton = uibutton(app.T1mapTab, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @EraseButtonPushed, true);
            app.EraseButton.Visible = 'off';
            app.EraseButton.Tooltip = {'Erase ROI'};
            app.EraseButton.Text = 'Erase';
            app.T1ViewField = uieditfield(app.T1mapTab, 'text');
            app.T1ViewField.Editable = 'off';
            app.T1ViewField.Tooltip = {'T1 time'};
            app.T1msLabel = uilabel(app.T1mapTab);
            app.T1msLabel.Text = 'T1 (ms)';
            app.T1ViewField2 = uieditfield(app.T1mapTab, 'text');
            app.T1ViewField2.Editable = 'off';
            app.T1ViewField2.Visible = 'off';
            app.T1ViewField2.Tooltip = {'T1 time'};
            app.pixelValueLabel = uilabel(app.T1mapTab);
            app.pixelValueLabel.Text = 'Pixel value';
            app.ROIaveragedLabel = uilabel(app.T1mapTab);
            app.ROIaveragedLabel.Visible = 'off';
            app.ROIaveragedLabel.Text = 'ROI averaged';
            app.ExportRoiDataButton = uibutton(app.T1mapTab, 'push');
            app.ExportRoiDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportRoiDataButtonPushed, true);
            app.ExportRoiDataButton.Tooltip = {'Export T1 curve to CSV file'};
            app.ExportRoiDataButton.Text = 'Export';
            app.T1msLabel2 = uilabel(app.T1mapTab);
            app.T1msLabel2.Visible = 'off';
            app.T1msLabel2.Text = 'T1 (ms)';
            app.brushEditFieldLabel = uilabel(app.T1mapTab);
            app.brushEditFieldLabel.Visible = 'off';
            app.brushEditFieldLabel.Text = 'brush';
            app.BrushEditField = uieditfield(app.T1mapTab, 'numeric');
            app.BrushEditField.Limits = [1 5];
            app.BrushEditField.ValueDisplayFormat = '%.0f';
            app.BrushEditField.ValueChangedFcn = createCallbackFcn(app, @BrushEditFieldValueChanged, true);
            app.BrushEditField.Visible = 'off';
            app.BrushEditField.Tooltip = {'ROI brush size (pixels)'};
            app.BrushEditField.Value = 2;
            app.M0R2Tab = uitab(app.TabGroup3);
            app.M0R2Tab.Title = 'M0 & R^2';
            app.M0MapFig = uiaxes(app.M0R2Tab);
            app.M0MapFig.XTick = [];
            app.M0MapFig.YTick = [];
            app.R2MapFig = uiaxes(app.M0R2Tab);
            app.R2MapFig.XTick = [];
            app.R2MapFig.YTick = [];
            app.M0MapLabel = uilabel(app.M0R2Tab);
            app.M0MapLabel.Text = 'M0 map';
            app.R2MapLabel = uilabel(app.M0R2Tab);
            app.R2MapLabel.Text = 'R^2 map';
            app.ImageSelectorPanel2 = uipanel(app.M0R2Tab);
            app.ImageSelectorPanel2.Title = 'Image selection';
            app.SlicesSlider3 = uislider(app.ImageSelectorPanel2);
            app.SlicesSlider3.Limits = [1 200];
            app.SlicesSlider3.MajorTicks = [];
            app.SlicesSlider3.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider3ValueChanged, true);
            app.SlicesSlider3.MinorTicks = [];
            app.SlicesSlider3.Tooltip = {'Slice number slider'};
            app.SlicesSlider3.Value = 1;
            app.SliceEditField_2Label_2 = uilabel(app.ImageSelectorPanel2);
            app.SliceEditField_2Label_2.Text = 'Slice';
            app.SlicesEditField3 = uieditfield(app.ImageSelectorPanel2, 'numeric');
            app.SlicesEditField3.Limits = [1 Inf];
            app.SlicesEditField3.ValueDisplayFormat = '%.0f';
            app.SlicesEditField3.ValueChangedFcn = createCallbackFcn(app, @SlicesEditField3ValueChanged, true);
            app.SlicesEditField3.Tooltip = {'Slice number'};
            app.SlicesEditField3.Value = 1;
            app.DynamicsSlider3 = uislider(app.ImageSelectorPanel2);
            app.DynamicsSlider3.Limits = [1 200];
            app.DynamicsSlider3.MajorTicks = [];
            app.DynamicsSlider3.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider3ValueChanged, true);
            app.DynamicsSlider3.MinorTicks = [];
            app.DynamicsSlider3.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider3.Value = 1;
            app.DynamicLabel_3 = uilabel(app.ImageSelectorPanel2);
            app.DynamicLabel_3.Text = 'Dynamic';
            app.DynamicsEditField3 = uieditfield(app.ImageSelectorPanel2, 'numeric');
            app.DynamicsEditField3.Limits = [1 Inf];
            app.DynamicsEditField3.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField3.ValueChangedFcn = createCallbackFcn(app, @DynamicsEditField3ValueChanged, true);
            app.DynamicsEditField3.Tooltip = {'Dynamic number'};
            app.DynamicsEditField3.Value = 1;
            app.ColorMapButtonGroup2 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup2SelectionChanged, true);
            app.ColorMapButtonGroup2.Title = 'Color map';
            app.grayButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.grayButton2.Text = 'gray';
            app.grayButton2.Value = true;
            app.autumnButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.autumnButton2.Text = 'autumn';
            app.winterButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.winterButton2.Text = 'winter';
            app.parulaButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.parulaButton2.Text = 'parula';
            app.hotButton2 = uiradiobutton(app.ColorMapButtonGroup2);
            app.hotButton2.Text = 'hot';
            app.ColorMapButtonGroup3 = uibuttongroup(app.M0R2Tab);
            app.ColorMapButtonGroup3.SelectionChangedFcn = createCallbackFcn(app, @ColorMapButtonGroup3SelectionChanged, true);
            app.ColorMapButtonGroup3.Title = 'Color map';
            app.grayButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.grayButton3.Text = 'gray';
            app.autumnButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.autumnButton3.Text = 'autumn';
            app.winterButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.winterButton3.Text = 'winter';
            app.parulaButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.parulaButton3.Text = 'parula';
            app.parulaButton3.Value = true;
            app.hotButton3 = uiradiobutton(app.ColorMapButtonGroup3);
            app.hotButton3.Text = 'hot';
            app.M0Image = uiimage(app.M0R2Tab);
            app.M0Image.ImageClickedFcn = createCallbackFcn(app, @M0ImageClicked, true);
            app.R2Image = uiimage(app.M0R2Tab);
            app.ROIbutton2 = uibutton(app.M0R2Tab, 'push');
            app.ROIbutton2.ButtonPushedFcn = createCallbackFcn(app, @ROIbuttonButtonPushed, true);
            app.ROIbutton2.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton2.Text = 'ROI';
            app.EraseButton2 = uibutton(app.M0R2Tab, 'push');
            app.EraseButton2.Visible = 'off';
            app.EraseButton2.Tooltip = {'Erase ROI'};
            app.EraseButton2.Text = 'Erase';
            app.brushEditField_2Label = uilabel(app.M0R2Tab);
            app.brushEditField_2Label.Visible = 'off';
            app.brushEditField_2Label.Text = 'brush';
            app.BrushEditField2 = uieditfield(app.M0R2Tab, 'numeric');
            app.BrushEditField2.Limits = [1 5];
            app.BrushEditField2.ValueDisplayFormat = '%.0f';
            app.BrushEditField2.ValueChangedFcn = createCallbackFcn(app, @BrushEditField2ValueChanged, true);
            app.BrushEditField2.Visible = 'off';
            app.BrushEditField2.Tooltip = {'ROI brush size (pixels)'};
            app.BrushEditField2.Value = 2;
            app.TabGroup2 = uitabgroup(app.T1mappUIFigure);
            app.RawimagesTab = uitab(app.TabGroup2);
            app.RawimagesTab.Title = 'Raw images';
            app.RawDataFig = uiaxes(app.RawimagesTab);
            app.RawDataFig.XTick = [];
            app.RawDataFig.YTick = [];
            app.ImageLabel = uilabel(app.RawimagesTab);
            app.ImageLabel.Text = 'Image';
            app.ImageselectionPanel = uipanel(app.RawimagesTab);
            app.ImageselectionPanel.Title = 'Image selection';
            app.SlicesSlider1 = uislider(app.ImageselectionPanel);
            app.SlicesSlider1.Limits = [1 200];
            app.SlicesSlider1.MajorTicks = [];
            app.SlicesSlider1.ValueChangedFcn = createCallbackFcn(app, @SlicesSlider1ValueChanged, true);
            app.SlicesSlider1.MinorTicks = [];
            app.SlicesSlider1.Tooltip = {'Slice number slider'};
            app.SlicesSlider1.Value = 1;
            app.EchoesSlider = uislider(app.ImageselectionPanel);
            app.EchoesSlider.Limits = [1 200];
            app.EchoesSlider.MajorTicks = [];
            app.EchoesSlider.ValueChangedFcn = createCallbackFcn(app, @EchoesSliderValueChanged, true);
            app.EchoesSlider.MinorTicks = [];
            app.EchoesSlider.Tooltip = {'Inversion time number slider'};
            app.EchoesSlider.Value = 1;
            app.SliceEditFieldLabel = uilabel(app.ImageselectionPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.SlicesEditField1 = uieditfield(app.ImageselectionPanel, 'numeric');
            app.SlicesEditField1.Limits = [1 Inf];
            app.SlicesEditField1.ValueDisplayFormat = '%.0f';
            app.SlicesEditField1.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.SlicesEditField1.Tooltip = {'Slice number'};
            app.SlicesEditField1.Value = 1;
            app.InversionLabel = uilabel(app.ImageselectionPanel);
            app.InversionLabel.Text = 'Inversion';
            app.EchoesEditField = uieditfield(app.ImageselectionPanel, 'numeric');
            app.EchoesEditField.Limits = [1 Inf];
            app.EchoesEditField.ValueDisplayFormat = '%.0f';
            app.EchoesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.EchoesEditField.Tooltip = {'Inversion time number'};
            app.EchoesEditField.Value = 1;
            app.DynamicsSlider1 = uislider(app.ImageselectionPanel);
            app.DynamicsSlider1.Limits = [1 200];
            app.DynamicsSlider1.MajorTicks = [];
            app.DynamicsSlider1.ValueChangedFcn = createCallbackFcn(app, @DynamicsSlider1ValueChanged, true);
            app.DynamicsSlider1.MinorTicks = [];
            app.DynamicsSlider1.Tooltip = {'Dynamic number slider'};
            app.DynamicsSlider1.Value = 1;
            app.DynamicLabel = uilabel(app.ImageselectionPanel);
            app.DynamicLabel.Text = 'Dynamic';
            app.DynamicsEditField1 = uieditfield(app.ImageselectionPanel, 'numeric');
            app.DynamicsEditField1.Limits = [1 Inf];
            app.DynamicsEditField1.ValueDisplayFormat = '%.0f';
            app.DynamicsEditField1.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValue1Changed, true);
            app.DynamicsEditField1.Tooltip = {'Dynamic number'};
            app.DynamicsEditField1.Value = 1;
            app.MasksettingsPanel = uipanel(app.RawimagesTab);
            app.MasksettingsPanel.Title = 'Mask settings';
            app.ThresholdSlider = uislider(app.MasksettingsPanel);
            app.ThresholdSlider.Limits = [1 16384];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Tooltip = {'Mask threshold'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MasksettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MasksettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MasksettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MasksettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.ThresholdEditFieldLabel = uilabel(app.MasksettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.MasksettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold'};
            app.RawImage = uiimage(app.RawimagesTab);
            app.WindowSlider = uislider(app.RawimagesTab);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.LevelSlider = uislider(app.RawimagesTab);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.AutoLWButton = uibutton(app.RawimagesTab, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.WEditFieldLabel = uilabel(app.RawimagesTab);
            app.WEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.RawimagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.LEditFieldLabel = uilabel(app.RawimagesTab);
            app.LEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.RawimagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.RecoparametersTab = uitab(app.TabGroup2);
            app.RecoparametersTab.Title = 'Reco parameters';
            app.DeRingCheckBox = uicheckbox(app.RecoparametersTab);
            app.DeRingCheckBox.Enable = 'off';
            app.DeRingCheckBox.Tooltip = {'Check to apply Gibbs ringing suppresion filter'};
            app.DeRingCheckBox.Text = '';
            app.DeRingLabel = uilabel(app.RecoparametersTab);
            app.DeRingLabel.Enable = 'off';
            app.DeRingLabel.Tooltip = {'Gibbs ringing suppresion filter'};
            app.DeRingLabel.Text = 'deRing';
            app.DeNoiseCheckBox = uicheckbox(app.RecoparametersTab);
            app.DeNoiseCheckBox.Enable = 'off';
            app.DeNoiseCheckBox.Tooltip = {'Check for PCA denoising'};
            app.DeNoiseCheckBox.Text = '';
            app.DeNoiseLabel = uilabel(app.RecoparametersTab);
            app.DeNoiseLabel.Enable = 'off';
            app.DeNoiseLabel.Tooltip = {'PCA denoising filter'};
            app.DeNoiseLabel.Text = 'deNoise';
            app.DeNoiseWindowEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.DeNoiseWindowEditField.Limits = [2 64];
            app.DeNoiseWindowEditField.RoundFractionalValues = 'on';
            app.DeNoiseWindowEditField.ValueDisplayFormat = '%.0f';
            app.DeNoiseWindowEditField.Enable = 'off';
            app.DeNoiseWindowEditField.Tooltip = {'PCA denoising filter width (pixels)'};
            app.DeNoiseWindowEditField.Value = 8;
            app.FilteringLabel = uilabel(app.RecoparametersTab);
            app.FilteringLabel.Text = 'Filtering';
            app.TVteEditFieldLabel = uilabel(app.RecoparametersTab);
            app.TVteEditFieldLabel.Tooltip = {'Total Variation regulartization in TI dimension'};
            app.TVteEditFieldLabel.Text = 'TVti';
            app.TVdynEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.TVdynEditField.Limits = [0 20];
            app.TVdynEditField.ValueDisplayFormat = '%.4f';
            app.TVdynEditField.Enable = 'off';
            app.TVdynEditField.Tooltip = {'Total Variation constraint in dynamic dimension'};
            app.TVteEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.TVteEditField.Limits = [0 20];
            app.TVteEditField.ValueDisplayFormat = '%.4f';
            app.TVteEditField.Enable = 'off';
            app.TVteEditField.Tooltip = {'Total Variation regularization in TI dimension'};
            app.LLRxyzEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.LLRxyzEditField.Limits = [0 20];
            app.LLRxyzEditField.ValueDisplayFormat = '%.4f';
            app.LLRxyzEditField.Enable = 'off';
            app.LLRxyzEditField.Tooltip = {'Locally-Low Rank regularization in the spatial dimensions'};
            app.LLRxyzEditField.Value = 0.001;
            app.TVxyzEditFieldLabel = uilabel(app.RecoparametersTab);
            app.TVxyzEditFieldLabel.Tooltip = {'Total-variation regularization in the spatial dimensions'};
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.TVxyzEditField.Limits = [0 20];
            app.TVxyzEditField.ValueDisplayFormat = '%.4f';
            app.TVxyzEditField.Enable = 'off';
            app.TVxyzEditField.Tooltip = {'Total Variation regularization in spatial domain'};
            app.TVxyzEditField.Value = 0.001;
            app.WVxyzEditFieldLabel = uilabel(app.RecoparametersTab);
            app.WVxyzEditFieldLabel.Tooltip = {'Wavelet L1-norm regularization in the spatial dimensions'};
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.RecoparametersTab, 'numeric');
            app.WVxyzEditField.Limits = [0 20];
            app.WVxyzEditField.ValueDisplayFormat = '%.4f';
            app.WVxyzEditField.Enable = 'off';
            app.WVxyzEditField.Tooltip = {'Wavelet L1 regularization in spatial domain'};
            app.WVxyzEditField.Value = 0.001;
            app.TVdynEditFieldLabel = uilabel(app.RecoparametersTab);
            app.TVdynEditFieldLabel.Tooltip = {'Total-variation regularization in the dynamic dimension'};
            app.TVdynEditFieldLabel.Text = 'TVdyn';
            app.LLRxyzEditFieldLabel = uilabel(app.RecoparametersTab);
            app.LLRxyzEditFieldLabel.Tooltip = {'Locally-Low Rank regularization in the spatial dimensions'};
            app.LLRxyzEditFieldLabel.Text = 'LLRxyz';
            app.CSregularizationLabel = uilabel(app.RecoparametersTab);
            app.CSregularizationLabel.Text = 'CS regularization';
            app.FitparametersTab = uitab(app.TabGroup2);
            app.FitparametersTab.Title = 'Fit parameters';
            app.SliceselectionPanel = uipanel(app.FitparametersTab);
            app.SliceselectionPanel.Title = 'Slice selection';
            app.FirstsliceLabel = uilabel(app.SliceselectionPanel);
            app.FirstsliceLabel.Text = 'First slice';
            app.FirstSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.FirstSliceEditField.Limits = [1 200];
            app.FirstSliceEditField.ValueDisplayFormat = '%.0f';
            app.FirstSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.FirstSliceEditField.Tooltip = {'FIrst fit slice'};
            app.FirstSliceEditField.Value = 1;
            app.LastsliceLabel = uilabel(app.SliceselectionPanel);
            app.LastsliceLabel.Text = 'Last slice';
            app.LastSliceEditField = uieditfield(app.SliceselectionPanel, 'numeric');
            app.LastSliceEditField.Limits = [1 200];
            app.LastSliceEditField.ValueDisplayFormat = '%.0f';
            app.LastSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FirstSliceEditFieldValueChanged, true);
            app.LastSliceEditField.Tooltip = {'Last fit slice'};
            app.LastSliceEditField.Value = 1;
            app.GoodnessoffitPanel = uipanel(app.FitparametersTab);
            app.GoodnessoffitPanel.Title = 'Goodness of fit';
            app.MinRsquareLabel = uilabel(app.GoodnessoffitPanel);
            app.MinRsquareLabel.Text = 'Min R-square';
            app.Rsquare = uieditfield(app.GoodnessoffitPanel, 'numeric');
            app.Rsquare.Limits = [0 1];
            app.Rsquare.ValueDisplayFormat = '%.2f';
            app.Rsquare.Tooltip = {'R-square fitting criterium'};
            app.Rsquare.Value = 0.8;
            app.InversiontimeselectionPanel = uipanel(app.FitparametersTab);
            app.InversiontimeselectionPanel.Tooltip = {'Inversion time selection'};
            app.InversiontimeselectionPanel.Title = 'Inversion time selection';
            app.CheckBox_1 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_1.Text = '1';
            app.CheckBox_1.Value = true;
            app.CheckBox_2 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_2.Text = '2';
            app.CheckBox_2.Value = true;
            app.CheckBox_3 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_3.Text = '3';
            app.CheckBox_3.Value = true;
            app.CheckBox_4 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_4.Text = '4';
            app.CheckBox_4.Value = true;
            app.CheckBox_5 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_5.Text = '5';
            app.CheckBox_5.Value = true;
            app.CheckBox_6 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_6.Text = '6';
            app.CheckBox_6.Value = true;
            app.CheckBox_7 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_7.Text = '7';
            app.CheckBox_7.Value = true;
            app.CheckBox_8 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_8.Text = '8';
            app.CheckBox_8.Value = true;
            app.CheckBox_9 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_9.Text = '9';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_10.Text = '10';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_11.Text = '11';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_12.Text = '12';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_13.Text = '13';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_14.Text = '14';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_15.Text = '15';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_16.Text = '16';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_17.Text = '17';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_18.Text = '18';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_19.Text = '19';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_20.Text = '20';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_21.Text = '21';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_22.Text = '22';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_23.Text = '23';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_24.Text = '24';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_25.Text = '25';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_26.Text = '26';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_27.Text = '27';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_28.Text = '28';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_29.Text = '29';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_30.Text = '30';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_31.Text = '31';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_32.Text = '32';
            app.CheckBox_32.Value = true;
            app.CheckBox_33 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_33.Text = '33';
            app.CheckBox_33.Value = true;
            app.CheckBox_34 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_34.Text = '34';
            app.CheckBox_34.Value = true;
            app.CheckBox_35 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_35.Text = '35';
            app.CheckBox_35.Value = true;
            app.CheckBox_36 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_36.Text = '36';
            app.CheckBox_36.Value = true;
            app.CheckBox_37 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_37.Text = '37';
            app.CheckBox_37.Value = true;
            app.CheckBox_38 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_38.Text = '38';
            app.CheckBox_38.Value = true;
            app.CheckBox_39 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_39.Text = '39';
            app.CheckBox_39.Value = true;
            app.CheckBox_40 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_40.Text = '40';
            app.CheckBox_40.Value = true;
            app.CheckBox_41 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_41.Text = '41';
            app.CheckBox_41.Value = true;
            app.CheckBox_42 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_42.Text = '42';
            app.CheckBox_42.Value = true;
            app.CheckBox_43 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_43.Text = '43';
            app.CheckBox_43.Value = true;
            app.CheckBox_44 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_44.Text = '44';
            app.CheckBox_44.Value = true;
            app.CheckBox_45 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_45.Text = '45';
            app.CheckBox_45.Value = true;
            app.CheckBox_46 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_46.Text = '46';
            app.CheckBox_46.Value = true;
            app.CheckBox_47 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_47.Text = '47';
            app.CheckBox_47.Value = true;
            app.CheckBox_48 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_48.Text = '48';
            app.CheckBox_48.Value = true;
            app.CheckBox_49 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_49.Text = '49';
            app.CheckBox_49.Value = true;
            app.CheckBox_50 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_50.Text = '50';
            app.CheckBox_50.Value = true;
            app.CheckBox_51 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_51.Text = '51';
            app.CheckBox_51.Value = true;
            app.CheckBox_52 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_52.Text = '52';
            app.CheckBox_52.Value = true;
            app.CheckBox_53 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_53.Text = '53';
            app.CheckBox_53.Value = true;
            app.CheckBox_54 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_54.Text = '54';
            app.CheckBox_54.Value = true;
            app.CheckBox_55 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_55.Text = '55';
            app.CheckBox_55.Value = true;
            app.CheckBox_56 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_56.Text = '56';
            app.CheckBox_56.Value = true;
            app.CheckBox_57 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_57.Text = '57';
            app.CheckBox_57.Value = true;
            app.CheckBox_58 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_58.Text = '58';
            app.CheckBox_58.Value = true;
            app.CheckBox_59 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_59.Text = '59';
            app.CheckBox_59.Value = true;
            app.CheckBox_60 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_60.Text = '60';
            app.CheckBox_60.Value = true;
            app.CheckBox_61 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_61.Text = '61';
            app.CheckBox_61.Value = true;
            app.CheckBox_62 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_62.Text = '62';
            app.CheckBox_62.Value = true;
            app.CheckBox_63 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_63.Text = '63';
            app.CheckBox_63.Value = true;
            app.CheckBox_64 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_64.Text = '64';
            app.CheckBox_64.Value = true;
            app.CheckBox_65 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_65.Text = '65';
            app.CheckBox_65.Value = true;
            app.CheckBox_66 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_66.Text = '66';
            app.CheckBox_66.Value = true;
            app.CheckBox_67 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_67.Text = '67';
            app.CheckBox_67.Value = true;
            app.CheckBox_68 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_68.Text = '68';
            app.CheckBox_68.Value = true;
            app.CheckBox_69 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_69.Text = '69';
            app.CheckBox_69.Value = true;
            app.CheckBox_70 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_70.Text = '70';
            app.CheckBox_70.Value = true;
            app.CheckBox_71 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_71.Text = '71';
            app.CheckBox_71.Value = true;
            app.CheckBox_72 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_72.Text = '72';
            app.CheckBox_72.Value = true;
            app.CheckBox_73 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_73.Text = '73';
            app.CheckBox_73.Value = true;
            app.CheckBox_74 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_74.Text = '74';
            app.CheckBox_74.Value = true;
            app.CheckBox_75 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_75.Text = '75';
            app.CheckBox_75.Value = true;
            app.CheckBox_76 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_76.Text = '76';
            app.CheckBox_76.Value = true;
            app.CheckBox_77 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_77.Text = '77';
            app.CheckBox_77.Value = true;
            app.CheckBox_78 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_78.Text = '78';
            app.CheckBox_78.Value = true;
            app.CheckBox_79 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_79.Text = '79';
            app.CheckBox_79.Value = true;
            app.CheckBox_80 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_80.Text = '80';
            app.CheckBox_80.Value = true;
            app.CheckBox_81 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_81.Text = '81';
            app.CheckBox_81.Value = true;
            app.CheckBox_82 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_82.Text = '82';
            app.CheckBox_82.Value = true;
            app.CheckBox_83 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_83.Text = '83';
            app.CheckBox_83.Value = true;
            app.CheckBox_84 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_84.Text = '84';
            app.CheckBox_84.Value = true;
            app.CheckBox_85 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_85.Text = '85';
            app.CheckBox_85.Value = true;
            app.CheckBox_86 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_86.Text = '86';
            app.CheckBox_86.Value = true;
            app.CheckBox_87 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_87.Text = '87';
            app.CheckBox_87.Value = true;
            app.CheckBox_88 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_88.Text = '88';
            app.CheckBox_88.Value = true;
            app.CheckBox_89 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_89.Text = '89';
            app.CheckBox_89.Value = true;
            app.CheckBox_90 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_90.Text = '90';
            app.CheckBox_90.Value = true;
            app.CheckBox_91 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_91.Text = '91';
            app.CheckBox_91.Value = true;
            app.CheckBox_92 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_92.Text = '92';
            app.CheckBox_92.Value = true;
            app.CheckBox_93 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_93.Text = '93';
            app.CheckBox_93.Value = true;
            app.CheckBox_94 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_94.Text = '94';
            app.CheckBox_94.Value = true;
            app.CheckBox_95 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_95.Text = '95';
            app.CheckBox_95.Value = true;
            app.CheckBox_96 = uicheckbox(app.InversiontimeselectionPanel);
            app.CheckBox_96.Text = '96';
            app.CheckBox_96.Value = true;
            app.DynamicsSelectionPanel = uipanel(app.FitparametersTab);
            app.DynamicsSelectionPanel.Title = 'Dynamics selection';
            app.FirstsliceLabel_2 = uilabel(app.DynamicsSelectionPanel);
            app.FirstsliceLabel_2.Text = 'First dynamic';
            app.FirstDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.FirstDynamicEditField.Limits = [1 500];
            app.FirstDynamicEditField.ValueDisplayFormat = '%.0f';
            app.FirstDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.FirstDynamicEditField.Tooltip = {'First fit dynamic'};
            app.FirstDynamicEditField.Value = 1;
            app.LastsliceLabel_2 = uilabel(app.DynamicsSelectionPanel);
            app.LastsliceLabel_2.Text = 'Last dynamic';
            app.LastDynamicEditField = uieditfield(app.DynamicsSelectionPanel, 'numeric');
            app.LastDynamicEditField.Limits = [1 500];
            app.LastDynamicEditField.ValueDisplayFormat = '%.0f';
            app.LastDynamicEditField.ValueChangedFcn = createCallbackFcn(app, @FirstDynamicEditFieldValueChanged, true);
            app.LastDynamicEditField.Tooltip = {'Last fit dynamic'};
            app.LastDynamicEditField.Value = 1;
            app.TabGroup4 = uitabgroup(app.T1mappUIFigure);
            app.SequenceparametersTab = uitab(app.TabGroup4);
            app.SequenceparametersTab.Title = 'Sequence parameters';
            app.TRmsEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TRViewField.Limits = [0 Inf];
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TE0msLabel = uilabel(app.SequenceparametersTab);
            app.TE0msLabel.Text = 'first TI (ms)';
            app.TI0EditField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TI0EditField.Limits = [0 Inf];
            app.TI0EditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.TI0EditField.Tooltip = {'First inversion time'};
            app.dimxEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimxEditFieldLabel.Text = 'dimx';
            app.dimxViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimxViewField.Limits = [0 Inf];
            app.dimxViewField.ValueDisplayFormat = '%.0f';
            app.dimxViewField.Editable = 'off';
            app.dimxViewField.Tooltip = {'Readout matrix size'};
            app.dimyEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.dimyEditFieldLabel.Text = 'dimy';
            app.dimyViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.dimyViewField.Limits = [0 Inf];
            app.dimyViewField.ValueDisplayFormat = '%.0f';
            app.dimyViewField.Editable = 'off';
            app.dimyViewField.Tooltip = {'Phase-encoding matrix size'};
            app.SlicesEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.SlicesEditFieldLabel.Text = 'Slices';
            app.SlicesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SlicesViewField.Limits = [0 Inf];
            app.SlicesViewField.ValueDisplayFormat = '%.0f';
            app.SlicesViewField.Editable = 'off';
            app.SlicesViewField.Tooltip = {'Number of slices'};
            app.EchoesLabel = uilabel(app.SequenceparametersTab);
            app.EchoesLabel.Text = '# TIs';
            app.EchoesViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.EchoesViewField.Limits = [0 Inf];
            app.EchoesViewField.ValueDisplayFormat = '%.0f';
            app.EchoesViewField.Editable = 'off';
            app.EchoesViewField.Tooltip = {'Number of inversion times'};
            app.FOVmmEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.FOVmmEditFieldLabel.Text = 'FOV (mm)';
            app.FOVViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.FOVViewField.Limits = [0 Inf];
            app.FOVViewField.Editable = 'off';
            app.FOVViewField.Tooltip = {'Field-of-view'};
            app.SlicethicknessmmLabel = uilabel(app.SequenceparametersTab);
            app.SlicethicknessmmLabel.Text = 'Slice thickness (mm)';
            app.SLTViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.SLTViewField.Limits = [0 Inf];
            app.SLTViewField.Editable = 'off';
            app.SLTViewField.Tooltip = {'Slice thickness'};
            app.AspectratioLabel = uilabel(app.SequenceparametersTab);
            app.AspectratioLabel.Text = 'Aspect ratio';
            app.AspectRatioViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.AspectRatioViewField.Limits = [0.25 4];
            app.AspectRatioViewField.ValueDisplayFormat = '%.2f';
            app.AspectRatioViewField.Editable = 'off';
            app.AspectRatioViewField.Tooltip = {'Image aspect ratio'};
            app.AspectRatioViewField.Value = 1;
            app.DatafileEditFieldLabel = uilabel(app.SequenceparametersTab);
            app.DatafileEditFieldLabel.Text = 'Datafile';
            app.DatafileEditField = uieditfield(app.SequenceparametersTab, 'text');
            app.DatafileEditField.Editable = 'off';
            app.DatafileEditField.Tooltip = {'Data file name'};
            app.ESPmsLabel = uilabel(app.SequenceparametersTab);
            app.ESPmsLabel.Text = 'last TI (ms)';
            app.TISEditField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.TISEditField.Limits = [0 Inf];
            app.TISEditField.ValueChangedFcn = createCallbackFcn(app, @TEViewFieldValueChanged, true);
            app.TISEditField.Tooltip = {'Last inversion time'};
            app.EchoesLabel_2 = uilabel(app.SequenceparametersTab);
            app.EchoesLabel_2.Text = '# Dynamics';
            app.DynamicsViewField = uieditfield(app.SequenceparametersTab, 'numeric');
            app.DynamicsViewField.Limits = [0 Inf];
            app.DynamicsViewField.ValueDisplayFormat = '%.0f';
            app.DynamicsViewField.Editable = 'off';
            app.DynamicsViewField.Tooltip = {'Number of dynamics'};
            app.T1Label = uilabel(app.T1mappUIFigure);
            app.T1Label.Text = '1';
            % Show the figure after all components are created
            app.T1mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LLT1mapp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T1mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @StartupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T1mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T1mappUIFigure)
        end
    end
end